classdef phase_preview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        StartPreviewButton        matlab.ui.control.Button
        ExposureEditFieldLabel    matlab.ui.control.Label
        ExposureEditField         matlab.ui.control.NumericEditField
        EndPreviewButton          matlab.ui.control.Button
        SetROIButton              matlab.ui.control.Button
        ResetROIButton            matlab.ui.control.Button
        SnapshotButton            matlab.ui.control.Button
        CropFirstOrderButton      matlab.ui.control.Button
        GUIResetButton            matlab.ui.control.Button
        UpperLimitEditFieldLabel  matlab.ui.control.Label
        UpperLimitEditField       matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        ROIStatus                 matlab.ui.control.EditField
        LowLimitCheckBox          matlab.ui.control.CheckBox
        EditField_2Label          matlab.ui.control.Label
        FOStatus                  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        my_camera
        my_reconstructor
        previewing = false
        first_order_status = false
        roi = false
        
        frame
        first_order
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.my_reconstructor = reconstructor;
            
            app.EndPreviewButton.Enable = 'off';
            app.SetROIButton.Enable = 'off';
            app.ResetROIButton.Enable = 'off';
            app.SnapshotButton.Enable = 'off';
            app.CropFirstOrderButton.Enable = 'off';
            app.GUIResetButton.Enable = 'off';
            
            app.ROIStatus.Value = 'ROI not set';
            app.FOStatus.Value = 'First order not set';
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            app.my_camera = camera('Mono16', app.ExposureEditField.Value); % to-do: put somewhere else
            preview(app.my_camera.vid);
            app.previewing = true;
            
            app.EndPreviewButton.Enable = 'on';
            app.SetROIButton.Enable = 'on';
            app.ResetROIButton.Enable = 'off';
            app.SnapshotButton.Enable = 'on';
            app.StartPreviewButton.Enable = 'off';
        end

        % Button pushed function: EndPreviewButton
        function EndPreviewButtonPushed(app, event)
            if app.previewing
                closepreview(app.my_camera.vid);
                app.previewing = false;
                app.first_order = false;
                app.roi = false;
                imaqreset
                app.EndPreviewButton.Enable = 'off';
                app.SetROIButton.Enable = 'off';
                app.ResetROIButton.Enable = 'off';
                app.SnapshotButton.Enable = 'off';
                app.StartPreviewButton.Enable = 'on';
                app.CropFirstOrderButton.Enable = 'off';
            end
        end

        % Button pushed function: SetROIButton
        function SetROIButtonPushed(app, event)
            if app.previewing
                roi_frame = getsnapshot(app.my_camera.vid);
                figure('Name', 'Preview ROI Select')
                imshow(roi_frame, [min(roi_frame(:)) max(roi_frame(:))]);
                roi_draw = drawrectangle;
                roi_box = floor(roi_draw.Position);
                app.my_camera.vid.ROIPosition = roi_box;
                close 'Preview ROI Select';
                app.ROIStatus.Value = ['ROI Set: [' string(roi_box) ']'];
                app.roi = 1;
                app.ResetROIButton.Enable = 'on';
                app.first_order_status = false;
            end
        end

        % Button pushed function: ResetROIButton
        function ResetROIButtonPushed(app, event)
            if app.previewing
                app.my_camera.vid.ROIPosition = [0 0 2048 1536];
                app.ROIStatus.Value = ['ROI Reset: [' string([0 0 2048 1536]) ']'];
                app.ResetROIButton.Enable = 'off';
                app.first_order_status = false;
            end
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            if app.previewing
                app.frame = getsnapshot(app.my_camera.vid);
                imshow(app.frame, 'parent', app.UIAxes);
                fftimg = fftshift(fft2(app.frame));
                logimg = log(abs(fftimg)); % for illustration purpose only?
                logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
                if app.first_order_status == false
                    figure('name', 'Manual FFT Cropping');
                    imshow(logimgmaxmin);
                    roi_manual = drawrectangle;
                    close 'Manual FFT Cropping';
                    app.first_order_status = 1;
                    app.first_order = floor(roi_manual.Position);
                    app.FOStatus.Value = ['First Order Reset: [' string(app.first_order) ']'];
                end
                imgsize = size(fftimg); %determine image size
                fftimgcrop = fftimg(app.first_order(2):app.first_order(2) + app.first_order(4), app.first_order(1):app.first_order(1) + app.first_order(3)); % locate first order
                centreimg = zeros(imgsize(1), imgsize(2)); % create a black image for placing first order
                centreimg(floor(imgsize(1)/2):floor(imgsize(1)/2)+app.first_order(4), floor(imgsize(2)/2):floor(imgsize(2)/2)+app.first_order(3)) = fftimgcrop;
                show_crop_region = logimgmaxmin;
                show_crop_region(app.first_order(2):app.first_order(2) + app.first_order(4), app.first_order(1):app.first_order(1) + app.first_order(3)) = ...
                    show_crop_region(app.first_order(2):app.first_order(2) + app.first_order(4), app.first_order(1):app.first_order(1) + app.first_order(3)) + 0.2;
                imagesc(show_crop_region, 'parent', app.UIAxes_2);
                
                [intensity_no_curve, phase_unwrap_no_curve, ~, ~, ~, ~, ~] = process(0, centreimg, app.UpperLimitEditField.Value, app.LowLimitCheckBox.Value, 0, 0, 0, 0);
                imagesc(intensity_no_curve, 'parent', app.UIAxes_3);
                imagesc(phase_unwrap_no_curve, 'parent', app.UIAxes_4);
                mesh((phase_unwrap_no_curve), 'parent', app.UIAxes_5);
                
                app.CropFirstOrderButton.Enable = 'on';
            end
        end

        % Button pushed function: CropFirstOrderButton
        function CropFirstOrderButtonPushed(app, event)
            fftimg = fftshift(fft2(app.frame));
            logimg = log(abs(fftimg)); % for illustration purpose only?
            logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
            figure('name', 'Manual FFT Cropping');
            imshow(logimgmaxmin);
            roi_manual = drawrectangle;
            close 'Manual FFT Cropping';
            app.first_order_status = 1;
            app.first_order = floor(roi_manual.Position);
            app.FOStatus.Value = ['First Order Reset: [' string(app.first_order) ']'];
            imgsize = size(fftimg); %determine image size
            fftimgcrop = fftimg(app.first_order(2):app.first_order(2) + app.first_order(4), app.first_order(1):app.first_order(1) + app.first_order(3)); % locate first order
            centreimg = zeros(imgsize(1), imgsize(2)); % create a black image for placing first order
            centreimg(floor(imgsize(1)/2):floor(imgsize(1)/2)+app.first_order(4), floor(imgsize(2)/2):floor(imgsize(2)/2)+app.first_order(3)) = fftimgcrop;
            show_crop_region = logimgmaxmin;
            show_crop_region(app.first_order(2):app.first_order(2) + app.first_order(4), app.first_order(1):app.first_order(1) + app.first_order(3)) = ...
                show_crop_region(app.first_order(2):app.first_order(2) + app.first_order(4), app.first_order(1):app.first_order(1) + app.first_order(3)) + 0.2;
            imagesc(show_crop_region, 'parent', app.UIAxes_2);
            
            [intensity_no_curve, phase_unwrap_no_curve, ~, ~, ~, ~, ~] = process(0, centreimg, app.UpperLimitEditField.Value, app.LowLimitCheckBox.Value, 0, 0, 0, 0);
            imagesc(intensity_no_curve, 'parent', app.UIAxes_3);
            imagesc(phase_unwrap_no_curve, 'parent', app.UIAxes_4);
            mesh((phase_unwrap_no_curve), 'parent', app.UIAxes_5);
        end

        % Button pushed function: GUIResetButton
        function GUIResetButtonPushed(app, event)
            imaqreset;
            app.StartPreviewButton.Enable = 'on';
            app.EndPreviewButton.Enable = 'off';
            app.SetROIButton.Enable = 'off';
            app.ResetROIButton.Enable = 'off';
            app.SnapshotButton.Enable = 'off';
            app.CropFirstOrderButton.Enable = 'off';
            app.GUIResetButton.Enable = 'off';
            
            app.ROIStatus.Value = 'ROI not set';
            app.FOStatus.Value = 'First order not set';
            
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            
            app.previewing = false;
            app.first_order_status = false;
            app.roi = false;
            
            app.frame = false;
            app.first_order = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hologram')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FFT')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Intensity')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Phase')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Phase (mesh)')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.ZTick = [];
            app.StartPreviewButton = uibutton(app.UIFigure, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.ExposureEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExposureEditField.Limits = [12 15003];
            app.ExposureEditField.Value = 500;
            app.EndPreviewButton = uibutton(app.UIFigure, 'push');
            app.EndPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @EndPreviewButtonPushed, true);
            app.EndPreviewButton.Text = 'End Preview';
            app.SetROIButton = uibutton(app.UIFigure, 'push');
            app.SetROIButton.ButtonPushedFcn = createCallbackFcn(app, @SetROIButtonPushed, true);
            app.SetROIButton.Text = 'Set ROI';
            app.ResetROIButton = uibutton(app.UIFigure, 'push');
            app.ResetROIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIButtonPushed, true);
            app.ResetROIButton.Text = 'Reset ROI';
            app.SnapshotButton = uibutton(app.UIFigure, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Text = 'Snapshot';
            app.CropFirstOrderButton = uibutton(app.UIFigure, 'push');
            app.CropFirstOrderButton.ButtonPushedFcn = createCallbackFcn(app, @CropFirstOrderButtonPushed, true);
            app.CropFirstOrderButton.Text = 'Crop First Order';
            app.GUIResetButton = uibutton(app.UIFigure, 'push');
            app.GUIResetButton.ButtonPushedFcn = createCallbackFcn(app, @GUIResetButtonPushed, true);
            app.GUIResetButton.Text = 'GUI Reset';
            app.UpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperLimitEditField.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ROIStatus = uieditfield(app.UIFigure, 'text');
            app.ROIStatus.Editable = 'off';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.FOStatus = uieditfield(app.UIFigure, 'text');
            app.FOStatus.Editable = 'off';
            app.LowLimitCheckBox = uicheckbox(app.UIFigure);
            app.LowLimitCheckBox.Text = 'Low Limit';
            app.LowLimitCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phase_preview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end