classdef test_ui_combined_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DHMGUIUIFigure                 matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        ImagingLabel                   matlab.ui.control.Label
        SelectRegionofInterestButton   matlab.ui.control.Button
        ResetRegionofInterestButton    matlab.ui.control.Button
        StartCameraPreviewButton       matlab.ui.control.Button
        RecordHologramButton           matlab.ui.control.Button
        PhasePreviewButton             matlab.ui.control.Button
        CaptureSingleFrameButton       matlab.ui.control.Button
        ApplyChangesButton             matlab.ui.control.Button
        ExposuresEditFieldLabel        matlab.ui.control.Label
        ExposuresEditField             matlab.ui.control.NumericEditField
        FramerateEditFieldLabel        matlab.ui.control.Label
        FramerateEditField             matlab.ui.control.NumericEditField
        GainEditFieldLabel             matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        CameraDropDownLabel            matlab.ui.control.Label
        CameraDropDown                 matlab.ui.control.DropDown
        RecordPhaseButton              matlab.ui.control.Button
        SettingsLabel                  matlab.ui.control.Label
        RecordingLabel                 matlab.ui.control.Label
        RefracIndexDiffEditField_2Label  matlab.ui.control.Label
        RefracIndexDiffEditFieldImaging  matlab.ui.control.NumericEditField
        WavelengthnmEditField_2Label   matlab.ui.control.Label
        WavelengthnmEditFieldImaging   matlab.ui.control.NumericEditField
        LinePlotButton                 matlab.ui.control.Button
        MaximumPhasemLabel             matlab.ui.control.Label
        MaxPhaseHeightInput            matlab.ui.control.NumericEditField
        DigitalRefocusLabel            matlab.ui.control.Label
        OffsetmEditFieldLabel          matlab.ui.control.Label
        OffsetmEditField               matlab.ui.control.EditField
        DecreaseDigitalFocus           matlab.ui.control.Button
        IncreaseDigitalFocus           matlab.ui.control.Button
        PixelSizemLabel                matlab.ui.control.Label
        PixelSizeInput                 matlab.ui.control.NumericEditField
        QuickRefocusPreviewButton      matlab.ui.control.Button
        FrameGrabIntervalEditFieldLabel  matlab.ui.control.Label
        FrameGrabIntervalEditField     matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        version                        matlab.ui.control.TextArea
        Panel_2                        matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        PreviewButton                  matlab.ui.control.Button
        LoadImageFileButton            matlab.ui.control.Button
        SetRegionofInterestButton      matlab.ui.control.Button
        ProcessingLabel                matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        LowerLimitCheckBox             matlab.ui.control.CheckBox
        TextArea                       matlab.ui.control.TextArea
        OutputFolderEditField          matlab.ui.control.EditField
        ProcessButton                  matlab.ui.control.Button
        SummaryCheckBox                matlab.ui.control.CheckBox
        SelectOutputButton             matlab.ui.control.Button
        OutputFolderLabel              matlab.ui.control.Label
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField            matlab.ui.control.NumericEditField
        PixelSizemEditFieldLabel       matlab.ui.control.Label
        PixelSizemEditField            matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        WavelengthnmEditField          matlab.ui.control.NumericEditField
        RefracIndexDiffEditFieldLabel  matlab.ui.control.Label
        RefracIndexDiffEditField       matlab.ui.control.NumericEditField
        OutputOptionsLabel             matlab.ui.control.Label
        HologramCheckBox               matlab.ui.control.CheckBox
        QuantitativeHeightCheckBox     matlab.ui.control.CheckBox
        HeightMeshCheckBox             matlab.ui.control.CheckBox
        BrightfieldCheckBox            matlab.ui.control.CheckBox
        Panel_3                        matlab.ui.container.Panel
        ConverttoimagesButton          matlab.ui.control.Button
        FrameSkipEditFieldLabel        matlab.ui.control.Label
        FrameSkipEditField             matlab.ui.control.NumericEditField
        EndsecEditFieldLabel           matlab.ui.control.Label
        EndsecEditField                matlab.ui.control.NumericEditField
        StartsecEditFieldLabel         matlab.ui.control.Label
        StartsecEditField              matlab.ui.control.NumericEditField
        FFTFirstOrderSelectionSwitchLabel  matlab.ui.control.Label
        FFTFirstOrderSelectionSwitch   matlab.ui.control.Switch
        Image                          matlab.ui.control.Image
        CroppedRegionCheckBox          matlab.ui.control.CheckBox
        RefocusOffsetmEditFieldLabel   matlab.ui.control.Label
        RefocusOffsetmEditField        matlab.ui.control.NumericEditField
        HelpSupportButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        file_path  % file selection
        image_status
        video_status
        image_folder_path   % image folder selection
        image_folder_status
        capture_roi
        processing_roi
        desktop_path        % system desktop directory
        curve_fitting_frames
        
        my_recorder
        my_camera
        capture_reconstructor
        processing_reconstructor
        my_vid2img
        first_order
        
        previewing = false;
        fringe_plot = false;
        recording_hologram = false;
        recording_phase = false;
        fft_check_control
        live_recon_control
        um_nm_factor = 1000;
        dim_set = [.2 .5 .3 .3] % mesh annotation box size
        
        processing_images
        processing_first_order
        
        digital_focus_value = 0;
        frame_grab_interval = 1;
        processing_digital_focus_value = 0;
        live_preview_pixel_size = 0.176;
        live_preview_wavelength = 514;
    end
    
    methods (Access = private)
        function reset_imaging_setting(app)
            app.StartCameraPreviewButton.Enable = 'on';
            app.StopPreviewButton.Enable = 'off';
            app.RecordHologramButton.Enable = 'off';
            app.StopRecordingButton.Enable = 'off';
            app.PhasePreviewButton.Enable = 'off';
            app.QuickRefocusPreviewButton.Enable = 'off';
            app.SelectRegionofInterestButton.Enable = 'off';
            app.ResetRegionofInterestButton.Enable = 'off';
            app.CaptureSingleFrameButton.Enable = 'off';
            app.ApplyChangesButton.Enable = 'off';
        end
        
        function reset_input_selection(app)
            app.LoadImageFileButton.Enable = 'on';
            app.SelectFolderButton.Enable = 'on';
            app.PreviewButton.Enable = 'off';
            app.SetRegionofInterestButton.Enable = 'off';
            app.ConverttoimagesButton.Enable = 'off';
            app.FrameSkipEditField.Enable = 'off';
            app.StartsecEditField.Enable = 'off';
            app.EndsecEditField.Enable = 'off';
            app.ProcessButton.Enable = 'off';
            app.TextArea.Value = '';
            app.ImageFolderEditField.Value = 'image_folder';
            app.OutputFolderEditField.Value = 'output_folder';
            app.image_status = 0;
            app.video_status = 0;
            app.image_folder_status = 0;
            app.processing_images = 0;
            cla(app.UIAxes);
        end
        
        function process(app, auto)
            app.processing_reconstructor = reconstructor;
            
            if auto
                answer = inputdlg("Select Noise Level", "Auto FFT Cropping", 1, {'30'});
                if size(answer,1) == 0
                    drawnow;
                    figure(app.DHMGUIUIFigure);
                    return;
                end
                
                try
                    noise_level = str2num(answer{1});
                    if noise_level < 0 || noise_level > 100
                        errordlg("Please enter a valid number between 0 and 100");
                        return;
                    end
                catch
                    errordlg("Please enter a valid number between 0 and 100");
                    return;
                end
            end
            
            if app.image_status
                app.TextArea.Value = ['Processing image...'];
                
                tic;
                
                batch_process = 0;
                [app.processing_reconstructor, imgo, preview, fftimg] = app.processing_reconstructor.load_img(app.file_path, 1, app.processing_roi);
                
                if auto
                    [success, first_order] = app.processing_reconstructor.auto_fft_select(preview,noise_level);
                else
                    [success, first_order] = app.processing_reconstructor.manual_fft_select(preview);
                end
                
                if success == false
                    return;
                end
                app.processing_first_order = first_order;
                
                centreimg = app.processing_reconstructor.crop_fft(fftimg, first_order);
                
                [complex_amplitude, intensity_no_curve, phase_unwrap_no_curve, thickness, lower_limit, upper_limit, frame_peak_height, frame_volume] = app.processing_reconstructor.process(batch_process, centreimg, app.UpperLimitEditField.Value, ...
                    app.LowerLimitCheckBox.Value, 0, app.WavelengthnmEditField.Value, app.RefracIndexDiffEditField.Value, app.PixelSizemEditField.Value, app.processing_digital_focus_value);
                
                % highlight spatial region
                if app.processing_reconstructor.use_gpu()
                    show_crop_region = gather(preview);
                    imgo = gather(imgo);
                else
                    show_crop_region = preview;
                end
                
                show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) = ...
                    show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) + 0.2;
                imshow(show_crop_region, 'parent', app.UIAxes);
                
                figure('name', 'Processing Result');
                subplot(3, 2, 1), imagesc(imgo); title('Hologram');
                subplot(3, 2, 2), imagesc(show_crop_region); title('Spatial Frequency Cropping'); colorbar;
                subplot(3, 2, 3), imagesc(intensity_no_curve); title('Intensity'); colorbar;
                subplot(3, 2, 4), imagesc(phase_unwrap_no_curve); title('Phase'); colorbar;
                subplot(3, 2, 5), imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
                subplot(3, 2, 6), mesh(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]); set(gca,'Ydir','reverse');
                
                save_folder = app.OutputFolderEditField.Value;
                mkdir(save_folder);
                if app.HologramCheckBox.Value == 1
                    imwrite(uint16(imgo), [save_folder '\original.tif']);
                end
                if app.BrightfieldCheckBox.Value == 1
                    imwrite(uint16(intensity_no_curve), [save_folder '\intensity.tif']);
                end
                if app.QuantitativeHeightCheckBox.Value == 1
                    t = Tiff([save_folder '\thickness.tif'],'w');
                    tagstruct.ImageLength = size(thickness,1);
                    tagstruct.ImageWidth = size(thickness,2);
                    tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
                    tagstruct.Compression = Tiff.Compression.None;
                    tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                    tagstruct.BitsPerSample = 32;
                    tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                    tagstruct.SamplesPerPixel = 1;
                    setTag(t,tagstruct);
                    
                    write(t,single(thickness));
                    close(t);
                end
                if app.CroppedRegionCheckBox.Value ==1
                    imwrite(uint16(show_crop_region), [save_folder '\fft_cropped.tif'])
                end
                if app.HeightMeshCheckBox.Value == 1
                    imwrite(uint16(show_crop_region), [save_folder '\fft.tif'])
                    f = figure('visible','off');
                    mesh(thickness); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]); set(gca,'Ydir','reverse');
                    text = ['Peak Height: ' num2str(frame_peak_height) newline 'Volume: ' num2str(frame_volume)];
                    annotation('textbox', [.2 .5 .3 .3], 'String', text, 'FitBoxToText', 'on');
                    saveas(gca, [save_folder '\mesh.tif']);
                end
                
                % save complex amplitude
                if isa(complex_amplitude, 'gpuArray')
                    complex_amplitude = gather(complex_amplitude);
                end
                complex_amplitude_dir = [save_folder '\complex_amplitude.mat'];
                save(complex_amplitude_dir, 'complex_amplitude');
                
                % writematrix(thickness, [save_folder '\thickness.csv']);
                
                t = toc;
                
                app.TextArea.Value = ['Done!' newline 'Total time: ' num2str(t) 's' newline 'Saved at ' save_folder];
            end
            
            if app.image_folder_status
                app.TextArea.Value = ['Processing folder:'];
                
                tic;
                
                [up, save_folder, input] = app.processing_reconstructor.batch_make_folder(app.image_folder_path, ...
                    app.OutputFolderEditField.Value, 1, 1);
                
                app.TextArea.Value = ['Processing folder: ' num2str(length(up)) ' images found'];
                
                if length(up) > 1
                    use_same_fft = questdlg(['Multiple images found' newline 'Use the same FFT region for all images?'],'Yes','No');
                    
                    switch use_same_fft
                        case 'Yes'
                            use_same_fft = true;
                        case 'No'
                            use_same_fft = false;
                        otherwise
                            return;
                    end
                end
                
                % peak height and volume for frames
                [peak_height, volume, dim] = app.processing_reconstructor.height_volume_data(up, app.dim_set);
                
                count = 1;
                first_order = [0 0 0 0];
                
                for index = 1 : length(up)
                    close all;
                    file = up(index).name;
                    file_dir = strcat(input, '\', file);
                    
                    if app.processing_images == 0
                        break;
                    end
                    
                    [app.processing_reconstructor, imgo, logimgmaxmin, fftimg] = app.processing_reconstructor.load_img(file_dir, 1, app.processing_roi);
                    
                    if ~use_same_fft || index == 1
                        if auto
                            [success, first_order] = app.processing_reconstructor.auto_fft_select(logimgmaxmin,noise_level);
                        else
                            [success, first_order] = app.processing_reconstructor.manual_fft_select(logimgmaxmin);
                        end
                        
                        if success == false
                            break;
                        end
                    end
                    
                    centreimg = app.processing_reconstructor.crop_fft(fftimg, first_order);
                    
                    [complex_amplitude, intensity_no_curve, ~, thickness, lower_limit, upper_limit, frame_peak_height, frame_volume] = app.processing_reconstructor.process(1, centreimg, app.UpperLimitEditField.Value, ...
                        app.LowerLimitCheckBox.Value, 0, app.WavelengthnmEditField.Value, app.RefracIndexDiffEditField.Value, app.PixelSizemEditField.Value, app.processing_digital_focus_value);
                    
                    % hightlight spatial region
                    [show_crop_region] = app.processing_reconstructor.show_fft_crop(logimgmaxmin, first_order, app.UIAxes);
                    
                    % save intensity and thickness images
                    if app.HologramCheckBox.Value == 1
                        if isa(imgo, 'gpuArray')
                            imgo = gather(imgo);
                        end
                        imwrite(uint16(imgo), [save_folder '\Hologram\' file]);
                    end
                    if app.BrightfieldCheckBox.Value == 1
                        imwrite(uint16(intensity_no_curve), [save_folder '\intensity\' file]);
                    end
                    if app.QuantitativeHeightCheckBox.Value == 1
                        t = Tiff([save_folder '\thickness\' file],'w');
                        tagstruct.ImageLength = size(thickness,1);
                        tagstruct.ImageWidth = size(thickness,2);
                        tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
                        tagstruct.Compression = Tiff.Compression.None;
                        tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                        tagstruct.BitsPerSample = 32;
                        tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                        tagstruct.SamplesPerPixel = 1;
                        setTag(t,tagstruct);
                        
                        write(t,single(thickness));
                        close(t);
                    end
                    if app.CroppedRegionCheckBox.Value ==1
                        imwrite(uint16(show_crop_region), [save_folder '\fft\' file])
                    end
                    
                    if app.HeightMeshCheckBox.Value == 1
                        f = figure('visible','off');
                        mesh(thickness); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]); set(gca,'Ydir','reverse');
                        text = ['Peak Height: ' num2str(frame_peak_height) newline 'Volume: ' num2str(frame_volume)];
                        annotation('textbox', dim, 'String', text, 'FitBoxToText', 'on');
                        saveas(gca, [save_folder '\mesh\' 'mesh_' file]);
                    end
                    
                    % save complex amplitude
                    %if isa(complex_amplitude, 'gpuArray')
                    %    complex_amplitude = gather(complex_amplitude);
                    %end
                    % save([save_folder '\complex_amplitude\' file '.mat'], 'complex_amplitude');
                    
                    [peak_height, volume] = app.processing_reconstructor.single_frame_data(file, 1, 1, save_folder, ...
                        count, frame_peak_height, peak_height, frame_volume, volume, thickness); % natural file order bug
                    
                    app.TextArea.Value = ['Currently processing image: ' file newline num2str(length(up) - count) ' images to go...'];
                    
                    count = count + 1;
                end
                
                if app.SummaryCheckBox.Value == 1
                    app.processing_reconstructor.all_frame_data(1, 1, peak_height, volume, save_folder);
                end
                
                t = toc;
                
                app.TextArea.Value = ['Done! Total time: ' num2str(t) 's' newline 'Saved at ' save_folder];
            end
            
            if app.video_status
                tic
                
                [video, start_frame, total_frames, save_folder, peak_height, volume, dim] = app.processing_reconstructor.video_direct_batch_processing(app.OutputFolderEditField.Value, ...
                    app.file_path, app.StartsecEditField.Value, app.EndsecEditField.Value, app.FrameSkipEditField.Value, app.dim_set);
                
                count = 1;
                
                frame = read(video, 1);
                frame_cropped = imcrop(frame, [app.processing_roi(1) app.processing_roi(2) app.processing_roi(3) app.processing_roi(4)]);
                [app.processing_reconstructor, ~, logimgmaxmin, ~] = app.processing_reconstructor.load_img(frame_cropped, 0, app.processing_roi);
                
                if auto
                    [success, first_order] = app.processing_reconstructor.auto_fft_select(logimgmaxmin,noise_level);
                else
                    [success, first_order] = app.processing_reconstructor.manual_fft_select(logimgmaxmin);
                end
                
                if success == false
                    return;
                end
                
                for index = start_frame : app.FrameSkipEditField.Value : start_frame + total_frames - 1
                    close all;
                    if app.processing_images == 0
                        break;
                    end
                    
                    frame = read(video, index);
                    frame_cropped = imcrop(frame, [app.processing_roi(1) app.processing_roi(2) app.processing_roi(3) app.processing_roi(4)]);
                    [app.processing_reconstructor, ~, logimgmaxmin, fftimg] = app.processing_reconstructor.load_img(frame_cropped, 0, app.processing_roi);
                    centreimg = app.processing_reconstructor.crop_fft(fftimg, first_order);
                    
                    [complex_amplitude, intensity_no_curve, ~, thickness, lower_limit, upper_limit, frame_peak_height, frame_volume] = app.processing_reconstructor.process(count, centreimg, app.UpperLimitEditField.Value, ...
                        app.LowerLimitCheckBox.Value, 0, app.WavelengthnmEditField.Value, app.RefracIndexDiffEditField.Value, app.PixelSizemEditField.Value, app.processing_digital_focus_value);
                    
                    % hightlight spatial region
                    [show_crop_region] = app.processing_reconstructor.show_fft_crop(logimgmaxmin, first_order, app.UIAxes);
                    imshow(show_crop_region, 'parent', app.UIAxes);
                    
                    % show retrieve phase
                    % imagesc(thickness, 'parent', app.UIAxes); caxis([min(thickness, [], 'all'), max(thickness, [], 'all')]);
                    
                    % save intensity and thickness images
                    
                    file = ['cropped_' int2str(index), '.tif'];
                    
                    if app.BrightfieldCheckBox.Value == 1
                        imwrite(uint16(intensity_no_curve), [save_folder '\intensity\' file]);
                    end
                    if app.QuantitativeHeightCheckBox.Value == 1
                        t = Tiff([save_folder '\thickness\' file],'w');
                        tagstruct.ImageLength = size(thickness,1);
                        tagstruct.ImageWidth = size(thickness,2);
                        tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
                        tagstruct.Compression = Tiff.Compression.None;
                        tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                        tagstruct.BitsPerSample = 32;
                        tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                        tagstruct.SamplesPerPixel = 1;
                        setTag(t,tagstruct);
                        
                        write(t,single(thickness));
                        close(t);
                    end
                    if app.CroppedRegionCheckBox.Value == 1
                        imwrite(uint16(show_crop_region), [save_folder '\fft\' file])
                    end
                    if app.HeightMeshCheckBox.Value ==1
                        f = figure('visible','off');
                        mesh(thickness); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]); set(gca,'Ydir','reverse');
                        text = ['Peak Height: ' num2str(frame_peak_height) newline 'Volume: ' num2str(frame_volume)];
                        annotation('textbox', dim, 'String', text, 'FitBoxToText', 'on');
                        saveas(gca, [save_folder '\mesh\' 'mesh_' file]);
                    end
                    if app.HologramCheckBox.Value == 1
                        imwrite(frame_cropped, [save_folder '\Hologram\' file]);
                    end
                    
                    % save complex amplitude
                    %if isa(complex_amplitude, 'gpuArray')
                    %    complex_amplitude = gather(complex_amplitude);
                    %end
                    %save([save_folder '\complex_amplitude\' file '.mat'], 'complex_amplitude');
                    
                    [peak_height, volume] = app.processing_reconstructor.single_frame_data(file, 1, 1, save_folder, ...
                        count, frame_peak_height, peak_height, frame_volume, volume, thickness);
                    
                    app.TextArea.Value = ['Currently processing image: ' newline num2str(ceil(total_frames/app.FrameSkipEditField.Value) - index) ' images to go...'];
                    
                    count = count + 1;
                end
                
                if app.SummaryCheckBox.Value == 1
                    app.processing_reconstructor.all_frame_data(1, 1, peak_height, volume, save_folder);
                end
                t = toc;
                
                app.TextArea.Value = ['Done! Total time: ' num2str(t) 's' newline 'Saved at ' save_folder];
            end
        end
        
        function onStopHologramPreview(app)
            app.StartCameraPreviewButton.Text = 'Start Camera Preview';
            app.live_recon_control = 0;
            closepreview(app.my_camera.vid);
            app.previewing = false;
            
            app.RecordHologramButton.Enable = 'off';
            app.RecordPhaseButton.Enable = 'off';
            app.ApplyChangesButton.Enable = 'off';
            app.SelectRegionofInterestButton.Enable = 'off';
            app.ResetRegionofInterestButton.Enable = 'off';
            app.PhasePreviewButton.Enable = 'off';
            app.QuickRefocusPreviewButton.Enable = 'off';
            app.CaptureSingleFrameButton.Enable = 'off';
            app.LinePlotButton.Enable = 'off';
        end
        
        function onPhasePreviewStop(app)
            app.live_recon_control=0;
            app.PhasePreviewButton.Text = 'Phase Preview';
        end
        
        function onLinePlotClose(app)
            app.fringe_plot = false;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~, app.desktop_path] = system('echo %USERPROFILE%');
            app.desktop_path = strcat(app.desktop_path, '\Desktop\');
            app.my_recorder = recorder;
            app.capture_reconstructor = reconstructor;
            app.processing_reconstructor = reconstructor;
            app.my_vid2img = vid2img;
            app.processing_images = 0;
            app.curve_fitting_frames = 10;
            app.live_recon_control = 0;
            
            app.StartCameraPreviewButton.Enable = 'on';
            
            app.RecordHologramButton.Enable = 'off';
            
            app.ApplyChangesButton.Enable = 'off';
            app.PhasePreviewButton.Enable = 'off';
            app.PhasePreviewButton.Enable = 'off';
            
            app.LoadImageFileButton.Enable = 'on';
            app.SelectFolderButton.Enable = 'on';
            app.PreviewButton.Enable = 'off';
            app.SetRegionofInterestButton.Enable = 'off';
            app.ConverttoimagesButton.Enable = 'off';
            app.FrameSkipEditField.Enable = 'off';
            app.StartsecEditField.Enable = 'off';
            app.EndsecEditField.Enable = 'off';


        end

        % Button pushed function: StartCameraPreviewButton
        function StartCameraPreviewButtonPushed(app, event)
            if app.previewing == false
                imaqreset;
                try
                    app.my_camera = camera(app.CameraDropDown.Value, app.ExposuresEditField.Value, app.FramerateEditField.Value, app.frame_grab_interval); % to-do: put somewhere else
                catch ME
                    ME
                    errordlg('Could not find camera');
                    return;
                end
                
                if strcmp(app.my_camera.model, 'pixelfly')
                    app.FramerateEditField.Enable = 'off';
                    app.GainEditField.Enable = 'off';
                end
                
                if strcmp(app.my_camera.model, 'blackfly_s')
                    app.FramerateEditField.Value = max(0,app.my_camera.src.AcquisitionResultingFrameRate);
                    app.GainEditField.Value = app.my_camera.src.Gain;
                end
                if strcmp(app.my_camera.model, 'blackfly_s')
                    app.ExposuresEditField.Value = max(12,app.my_camera.src.ExposureTime);
                elseif strcmp(app.my_camera.model, 'pixelfly')
                    app.ExposuresEditField.Value = app.my_camera.src.E2ExposureTime;
                end
                
                preview_window = preview(app.my_camera.vid);
                
                app.previewing = true;
                app.StartCameraPreviewButton.Text = 'Stop Preview';
                app.SelectRegionofInterestButton.Enable = 'on';
                app.ResetRegionofInterestButton.Enable = 'on';
                app.ApplyChangesButton.Enable = 'on';
                app.RecordHologramButton.Enable = 'on';
                app.RecordPhaseButton.Enable = 'on';
                app.ApplyChangesButton.Enable = 'on';
                app.LinePlotButton.Enable = 'on';
                
                app.PhasePreviewButton.Enable = 'on';
                app.QuickRefocusPreviewButton.Enable = 'on';
                app.CaptureSingleFrameButton.Enable = 'on';
                
                figure_window = ancestor(preview_window,'figure');
                addlistener(figure_window,'ObjectBeingDestroyed',@(~,~)onStopHologramPreview(app));
                
            elseif app.previewing == true                
                onStopHologramPreview(app);
            end
        end

        % Button pushed function: RecordHologramButton
        function RecordHologramButtonPushed(app, event)
                if app.previewing == true
                    if app.recording_hologram == false
                        filter = {'*.avi'};
                        [file, path] = uiputfile(filter);
                        
                        if ~(isequal(file,0) || isequal(path,0))
                            %app.my_camera.set_frame_grab_interval(app.frame_grab_interval);
                            app.my_recorder.start(app.my_camera, fullfile(path,file), app.frame_grab_interval);
                            app.StartCameraPreviewButton.Enable = 'off';
                            app.RecordHologramButton.Text = 'Stop Recording';
                            app.ApplyChangesButton.Enable = 'off';
                            app.ResetRegionofInterestButton.Enable = 'off';
                            app.SelectRegionofInterestButton.Enable = 'off';
                            app.recording_hologram = true;
                        end
                    else
                        app.my_recorder.stop(app.my_camera);
                        %app.my_camera.set_frame_grab_interval(1);
                        
                        app.StartCameraPreviewButton.Enable = 'on';
                        app.ApplyChangesButton.Enable = 'on';
                        app.ResetRegionofInterestButton.Enable = 'on';
                        app.SelectRegionofInterestButton.Enable = 'on';
                        app.RecordHologramButton.Text = 'Record Hologram';
                        app.recording_hologram = false;
                    end
                end
        end

        % Callback function
        function StopRecordingButtonPushed(app, event)
            if app.previewing == true
                app.my_recorder.stop(app.my_camera);
                
                app.StartCameraPreviewButton.Enable = 'off';
                app.StopPreviewButton.Enable = 'on';
                app.RecordHologramButton.Enable = 'on';
                app.StopRecordingButton.Enable = 'off';
                app.ApplyChangesButton.Enable = 'on';
                app.ResetRegionofInterestButton.Enable = 'off';
                app.SelectRegionofInterestButton.Enable = 'off';
                app.PhasePreviewButton.Enable = 'on';
                app.QuickRefocusPreviewButton.Enable = 'on';
                app.LinePlotButton.Enable = 'off';
            end
        end

        % Callback function
        function StopPreviewButtonPushed(app, event)
            if app.previewing == true
                app.live_recon_control = 0;
                closepreview(app.my_camera.vid);
                app.previewing = false;
                
                app.StartCameraPreviewButton.Enable = 'on';
                app.StopPreviewButton.Enable = 'off';
                app.RecordHologramButton.Enable = 'off';
                app.StopRecordingButton.Enable = 'off';
                app.ApplyChangesButton.Enable = 'off';
                app.SelectRegionofInterestButton.Enable = 'off';
                app.ResetRegionofInterestButton.Enable = 'off';
                app.PhasePreviewButton.Enable = 'off';
                app.QuickRefocusPreviewButton.Enable = 'off';
                app.CaptureSingleFrameButton.Enable = 'off';
            end
            imaqreset;
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            % if strcmp(app.my_camera.mode, app.CameraDropDown.Value)
            if strcmp(app.my_camera.model, 'blackfly_s')
                app.my_camera.src.ExposureTime = app.ExposuresEditField.Value;
            elseif strcmp(app.my_camera.model, 'pixelfly')
                app.my_camera.src.E2ExposureTime = app.ExposuresEditField.Value;
            end
            if strcmp(app.my_camera.model, 'blackfly_s')
                app.my_camera.src.AcquisitionFrameRate = app.FramerateEditField.Value;
                app.my_camera.src.Gain = app.GainEditField.Value;
            end
            
            % else
            %     StartCameraPreviewButtonPushed(app, event);
            % end
            
            app.live_preview_pixel_size = app.PixelSizeInput.Value;
            app.live_preview_wavelength = app.WavelengthnmEditFieldImaging.Value;
            
            
            % Set Frame Grab Interval
            value = app.FrameGrabIntervalEditField.Value;
            app.frame_grab_interval = value;

        end

        % Callback function
        function ROISelectButtonPushed(app, event)
            
        end

        % Callback function
        function ROIResetButtonPushed(app, event)
            app.my_camera.vid.ROIPosition = [384 368 1280 800];
        end

        % Callback function
        function ShowFFTButtonPushed(app, event)
            app.fft_check_control = 1;
            figure('Name', 'FFT Check');
            while app.fft_check_control == 1
                img = getsnapshot(app.my_camera.vid);
                logimg = log(abs(fftshift(fft2(double(img)))));
                logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
                imagesc(logimgmaxmin);
            end
            close 'FFT Check';
        end

        % Callback function
        function CloseFFTButtonPushed(app, event)
            app.fft_check_control = 0;
        end

        % Button pushed function: PhasePreviewButton
        function PhasePreviewButtonPushed(app, event)
            app.live_recon_control
            if app.live_recon_control == 0
                app.capture_reconstructor = reconstructor;
                
                % figure('Name', 'FFT Cropping for Live Reconstruction');
                frame = getsnapshot(app.my_camera.vid);
                
                try
                    gpuDevice;
                    frame = gpuArray(frame);
                catch ME
                    warndlg("GPU not enabled.");
                end
                app.capture_roi = [1 1 size(frame,2) size(frame, 1)];
                
                frame_cropped = imcrop(frame, [app.capture_roi(1) app.capture_roi(2) app.capture_roi(3) app.capture_roi(4)]);
                [app.capture_reconstructor, ~, logimgmaxmin, ~] = app.capture_reconstructor.load_img(frame_cropped, 0, app.capture_roi);
                [success, first_order] = app.capture_reconstructor.manual_fft_select(logimgmaxmin);
                
                if ~success
                    return;
                end
                
                app.first_order = first_order;
                
                videoplayer = vision.VideoPlayer();
                addlistener(videoplayer,'ObjectBeingDestroyed',@(~,~)onPhasePreviewStop(app));
                app.live_recon_control = 1;
                
                i=0;
                
                if app.live_recon_control == 1
                    app.PhasePreviewButton.Text = 'Stop Preview';
                end
                
                while app.live_recon_control == 1
                    frame_cropped = imcrop(frame, [app.capture_roi(1) app.capture_roi(2) app.capture_roi(3) app.capture_roi(4)]);
                    
                    [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, first_order,i,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, app.digital_focus_value);
                                     
                    step(videoplayer, unwrapped_phase);
                    try
                        frame = getsnapshot(app.my_camera.vid);
                    catch
                        app.live_recon_control = 0;
                        app.PhasePreviewButton.Text = 'Phase Preview';
                    end
                    i = i+1;
                end
                
                if app.live_recon_control == 0
                    app.PhasePreviewButton.Text = 'Phase Preview';
                end
                
                release(videoplayer);
            elseif app.live_recon_control == 1
                app.live_recon_control = 0;
                app.PhasePreviewButton.Text = 'Phase Preview';
            end
        end

        % Callback function
        function CloseReconButtonPushed(app, event)
            app.live_recon_control = 0;
            close 'Live Reconstruction';
        end

        % Value changed function: ExposuresEditField
        function ExposuresEditFieldValueChanged(app, event)
            if strcmp(app.my_camera.model, 'blackfly_s')
                app.my_camera.src.ExposureTime = app.ExposuresEditField.Value;
            elseif strcmp(app.my_camera.model, 'pixelfly')
                app.my_camera.src.E2ExposureTime = app.ExposuresEditField.Value;
            end
        end

        % Close request function: DHMGUIUIFigure
        function DHMGUIUIFigureCloseRequest(app, event)
            if app.previewing == true
                closepreview(app.my_camera.vid);
                app.previewing = false;
            end
            delete(app);
            imaqreset;
        end

        % Button pushed function: LoadImageFileButton
        function LoadImageFileButtonPushed(app, event)
            [name, folder] = uigetfile({'*.avi; *.tif; *.tiff'}, 'Select image or video', app.desktop_path);
            drawnow;
            figure(app.DHMGUIUIFigure);
            if ~isequal(name,0)
                app.file_path = fullfile(folder, name);
                if strcmp(name(end-3:end), '.avi')
                    app.TextArea.Value = ['Video selected: ' app.file_path];
                    video = VideoReader(app.file_path);
                    app.EndsecEditField.Value = video.Duration;
                    app.OutputFolderEditField.Value = [app.desktop_path name '_output_folder'];
                    
                    app.StartsecEditField.Enable = 'on';
                    app.EndsecEditField.Enable = 'on';
                    app.ConverttoimagesButton.Enable = 'on';
                    app.FrameSkipEditField.Enable = 'on';
                    app.video_status = 1;
                    app.image_status = 0;
                    app.image_folder_status = 0;
                else
                    app.TextArea.Value = ['Image selected: ' app.file_path];
                    app.StartsecEditField.Enable = 'off';
                    app.EndsecEditField.Enable = 'off';
                    app.ConverttoimagesButton.Enable = 'off';
                    app.FrameSkipEditField.Enable = 'off';
                    app.OutputFolderEditField.Value = [app.desktop_path name '_output_folder'];
                    app.image_status = 1;
                    app.video_status = 0;
                    app.image_folder_status = 0;
                end
                app.PreviewButton.Enable = 'on';
                app.SetRegionofInterestButton.Enable = 'on';
                
                if app.image_status
                    image = imread(app.file_path);
                end
                if app.video_status
                    video = VideoReader(app.file_path);
                    video.CurrentTime = app.StartsecEditField.Value;
                    image = readFrame(video);
                end
    
                app.processing_roi = [1 1 size(image,2) size(image,1)];
                imshow(image, 'parent', app.UIAxes);
                
                if app.video_status
                    app.ConverttoimagesButton.Enable = 'on';
                end
                app.ProcessButton.Enable = 'on';
            else
                
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.image_folder_path = uigetdir(app.desktop_path, 'Select image folder');
            drawnow;
            figure(app.DHMGUIUIFigure);
            if ~isequal(app.image_folder_path,0)
                contents = dir([app.image_folder_path '\*.tif']);
                if ~isempty(contents)
                    [~,name] = fileparts(app.image_folder_path);
                    up = dir(strcat(app.image_folder_path, '\*.tif'));
                    app.TextArea.Value = ['Image Folder selected: ' app.image_folder_path newline num2str(length(up)) ' images found'];
                    app.OutputFolderEditField.Value = [app.desktop_path name '_output_folder'];
                    app.StartsecEditField.Enable = 'off';
                    app.EndsecEditField.Enable = 'off';
                    app.ConverttoimagesButton.Enable = 'off';
                    app.FrameSkipEditField.Enable = 'off';
                    app.PreviewButton.Enable = 'off';
                    app.image_status = 0;
                    app.video_status = 0;
                    app.image_folder_status = 1;
                    
                    app.image_folder_path
                    contents = dir([app.image_folder_path '\*.tif']);
                    first_image_name = contents(1).name;
                    image = imread([app.image_folder_path '\' first_image_name]);
               
                    app.processing_roi = [1 1 size(image,2) size(image,1)];
                    imshow(image, 'parent', app.UIAxes);
                    
                    app.SetRegionofInterestButton.Enable = 'on';
                    app.ProcessButton.Enable = 'on';
                else
                    app.TextArea.Value = 'Folder is empty, select a new folder.';
                end
            else
                
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if app.image_status
                figure('Name', 'Image Preview');
                imshow(app.file_path);
            end
            if app.video_status
                implay(app.file_path);
            end
        end

        % Button pushed function: SetRegionofInterestButton
        function SetRegionofInterestButtonPushed(app, event)
                if app.image_status
                    image = imread(app.file_path);
                end
                if app.video_status
                    video = VideoReader(app.file_path);
                    video.CurrentTime = app.StartsecEditField.Value;
                    image = readFrame(video);
                end
                if app.image_folder_status
                    app.image_folder_path
                    contents = dir([app.image_folder_path '\*.tif']);
                    first_image_name = contents(1).name;
                    image = imread([app.image_folder_path '\' first_image_name]);
                end
                
                figure('name', 'Processing Analysis Area Select');
                imshow(image);
                roi = drawrectangle;
                try
                    app.processing_roi = ceil(roi.Position);
                catch
                    return
                end
                close 'Processing Analysis Area Select';
                line_width = 10;
                image(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + line_width) = 255; % right
                image(app.processing_roi(2) : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) + app.processing_roi(3) - 1 - line_width : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % left
                image(app.processing_roi(2) : app.processing_roi(2) + line_width, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % top
                image(app.processing_roi(2) + app.processing_roi(4) - 1 - line_width : app.processing_roi(2) + app.processing_roi(4) - 1, app.processing_roi(1) : app.processing_roi(1) + app.processing_roi(3) - 1) = 255; % bot
                imshow(image, 'parent', app.UIAxes);
                
                if app.video_status
                    app.ConverttoimagesButton.Enable = 'on';
                end
                app.ProcessButton.Enable = 'on';
        end

        % Button pushed function: ConverttoimagesButton
        function ConverttoimagesButtonPushed(app, event)
            app.image_folder_path = uigetdir(app.desktop_path, 'Select image folder');
            drawnow;
            figure(app.DHMGUIUIFigure);
            if ~isequal(app.image_folder_path,0)
                app.TextArea.Value = 'Converting...';
                tic;
                [video, start_frame, end_frame] = app.my_vid2img.frame_calc(app.file_path, app.StartsecEditField.Value, app.EndsecEditField.Value);
                save_folder = app.my_vid2img.make_folder(app.image_folder_path);
                line_width = 5;
                app.my_vid2img.convert(save_folder, video, app.processing_roi, app.FrameSkipEditField.Value, start_frame, end_frame, line_width);
                t = toc;
                app.TextArea.Value = ['Done! Total time: ' num2str(t) 's'];
            end
        end

        % Callback function
        function ManualButtonPushed(app, event)
            app.process(false);
        end

        % Callback function
        function AutoButtonPushed(app, event)
            app.process(true);
        end

        % Button pushed function: CaptureSingleFrameButton
        function CaptureSingleFrameButtonPushed(app, event)
                filter = {'*.tiff'};
                [file, path] = uiputfile(filter);
                
                if ~(isequal(file,0) || isequal(path,0))
                    frame = app.my_recorder.snap(app.my_camera);
                    imwrite(frame, fullfile(path,file));
                end
        end

        % Button pushed function: SelectRegionofInterestButton
        function SelectRegionofInterestButtonPushed(app, event)
            frame = getsnapshot(app.my_camera.vid);
            figure('Name', 'Preview Analysis Area Select')
            imshow(frame, [min(frame(:)) max(frame(:))]);
            roi = drawrectangle;
            try
                app.live_recon_control = 0;
                app.my_camera.vid.ROIPosition = floor(roi.Position);
                preview(app.my_camera.vid);
            catch
            end
            close 'Preview Analysis Area Select';
        end

        % Button pushed function: ResetRegionofInterestButton
        function ResetRegionofInterestButtonPushed(app, event)
            app.live_recon_control = 0;
            if strcmp(app.my_camera.model, 'blackfly_s')
                app.my_camera.vid.ROIPosition = [384 368 1280 800];
            elseif strcmp(app.my_camera.model, 'pixelfly')
                app.my_camera.vid.ROIPosition = [0 0 1392 1040];
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            dos('explorer https://github.com/PurelyWhite/DHM_MATLAB_ANUAOLAB/wiki#fft-region-selection'); %Explorer
        end

        % Button pushed function: HelpSupportButton
        function HelpSupportButtonPushed(app, event)
            dos('explorer https://github.com/PurelyWhite/DHM_MATLAB_ANUAOLAB/wiki'); %Explorer
        end

        % Button pushed function: SelectOutputButton
        function SelectOutputButtonPushed(app, event)
            output_folder_path = uigetdir(app.desktop_path, 'Select output folder');
            drawnow;
            figure(app.DHMGUIUIFigure);
            if ~isequal(output_folder_path,0)
                app.OutputFolderEditField.Value = output_folder_path;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if app.processing_images == 0
                app.processing_images = 1;
                app.ProcessButton.Text = 'Stop';
                app.ProcessButton.BackgroundColor = [1.00,0.75,0.75];
                
                switch app.FFTFirstOrderSelectionSwitch.Value
                    case 'Manual'
                        app.process(false);
                    case 'Auto'
                        app.process(true);
                end
                
                app.processing_images = 0;
                app.ProcessButton.Text = "Process";
                app.ProcessButton.BackgroundColor = [0.75,1.00,0.75];
            else
               app.processing_images = 0;
            end
        end

        % Value changed function: FramerateEditField
        function FramerateEditFieldValueChanged(app, event)
            app.my_camera.src.AcquisitionFrameRate = app.FramerateEditField.Value;
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            app.my_camera.src.Gain = app.GainEditField.Value;
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            if strcmp(app.CameraDropDown.Value, 'blackfly_s')
                app.FramerateEditField.Enable = 'on';
                app.GainEditField.Enable = 'on';
            elseif strcmp(app.CameraDropDown.Value, 'pixelfly')
                app.FramerateEditField.Enable = 'off';
                app.GainEditField.Enable = 'off';
            end
            app.reset_imaging_setting;
            imaqreset;
        end

        % Callback function
        function AdvancedSettingsButtonPushed(app, event)
            prompt = {'Curve fitting calculation'};
            dlgtitle = '# of frames per ';
            definput = {str(app.curve_fitting_frames)};
            opts.Interpreter = 'tex';
            app.curve_fitting_frames = uint8(inputdlg(prompt,dlgtitle,[1 40],definput,opts));
        end

        % Button pushed function: RecordPhaseButton
        function RecordPhaseButtonPushed(app, event)
                if app.recording_phase == false
                    save_folder = uigetdir();
                    
                    if ~isequal(save_folder,0)
                        
                        app.capture_reconstructor = reconstructor;
                        
                        % figure('Name', 'FFT Cropping for Live Reconstruction');
                        frame = getsnapshot(app.my_camera.vid);
                        
                        try
                            gpuDevice;
                            frame = gpuArray(frame);
                        catch ME
                            warndlg("GPU not enabled.");
                        end
                        app.capture_roi = [1 1 size(frame,2) size(frame, 1)];
                        
                        frame_cropped = imcrop(frame, [app.capture_roi(1) app.capture_roi(2) app.capture_roi(3) app.capture_roi(4)]);
                        [app.capture_reconstructor, ~, logimgmaxmin, fftimg] = app.capture_reconstructor.load_img(frame_cropped, 0, app.capture_roi);
                        [success, first_order] = app.capture_reconstructor.manual_fft_select(logimgmaxmin);
                        
                        if ~success
                            return;
                        end
                        
                        app.recording_phase = true;
                        
                        app.StartCameraPreviewButton.Enable = 'off';
                        app.RecordPhaseButton.Text = 'Stop Recording';
                        app.ApplyChangesButton.Enable = 'off';
                        app.ResetRegionofInterestButton.Enable = 'off';
                        app.SelectRegionofInterestButton.Enable = 'off';
                        app.recording_phase = true;
                        
                        i=0;
                        tic;
                        
                        while app.recording_phase == true
                            frame_cropped = imcrop(frame, [app.capture_roi(1) app.capture_roi(2) app.capture_roi(3) app.capture_roi(4)]);
                            [app.capture_reconstructor, ~, ~, fftimg] = app.capture_reconstructor.load_img(frame_cropped, 0, app.capture_roi);
                            %[app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, first_order,i);
                            centreimg = app.processing_reconstructor.crop_fft(fftimg, first_order);
                            
                            [~, ~, ~, thickness, ~, ~, ~, ~] = app.capture_reconstructor.process(0, centreimg, 20, false, 0, app.WavelengthnmEditFieldImaging.Value, ...
                                app.RefracIndexDiffEditFieldImaging.Value, 0.5);
                            strcat(save_folder, '\', string(i), '.tiff')
                            t = Tiff(strcat(save_folder, '\', string(round(toc*1000)), 'ms.tiff'),'w');
                            tagstruct.ImageLength = size(thickness,1);
                            tagstruct.ImageWidth = size(thickness,2);
                            tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
                            tagstruct.Compression = Tiff.Compression.None;
                            tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                            tagstruct.BitsPerSample = 32;
                            tagstruct.SampleFormat = Tiff.SampleFormat.IEEEFP;
                            tagstruct.SamplesPerPixel = 1;setTag(t,tagstruct);
                        
                            write(t,single(thickness));
                            close(t);
                            
                            try
                                frame = getsnapshot(app.my_camera.vid);
                            catch
                                app.recording_phase = false;
                                app.StartCameraPreviewButton.Enable = 'on';
                                app.ApplyChangesButton.Enable = 'on';
                                app.ResetRegionofInterestButton.Enable = 'off';
                                app.SelectRegionofInterestButton.Enable = 'off';
                                app.RecordPhaseButton.Text = 'Record Phase';
                            end
                            i = i+1;
                        end
                    end
                else
                    app.StartCameraPreviewButton.Enable = 'on';
                    app.ApplyChangesButton.Enable = 'on';
                    app.ResetRegionofInterestButton.Enable = 'off';
                    app.SelectRegionofInterestButton.Enable = 'off';
                    app.RecordPhaseButton.Text = 'Record Phase';
                    app.recording_phase = false;
                end
        end

        % Button pushed function: LinePlotButton
        function LinePlotButtonPushed(app, event)

            if app.fringe_plot == false
                fig = figure('Name', 'Fringe Plot');
                addlistener(fig,'ObjectBeingDestroyed',@(~,~)onLinePlotClose(app));
                
                app.fringe_plot = true;
                
                while app.fringe_plot
                    try
                        frame = getsnapshot(app.my_camera.vid);
                        
                        sz = size(frame);
                        
                        dist = min(sz(1),sz(2));
                        
                        row = flip((1:dist));
                        col = (1:dist);
                        
                        ind = sub2ind(sz,row,col);
                        
                        diag = frame(ind);
                        if app.fringe_plot
                            plot(diag);
                            ylim([0 65535]);
                        end
                    catch
                        app.fringe_plot = false;
                    end
                end
            end
        end

        % Button pushed function: DecreaseDigitalFocus
        function DecreaseDigitalFocusPushed(app, event)
            app.digital_focus_value = app.digital_focus_value - 0.5;
            app.OffsetmEditField.Value = num2str(app.digital_focus_value);
        end

        % Button pushed function: IncreaseDigitalFocus
        function IncreaseDigitalFocusButtonPushed(app, event)
            app.digital_focus_value = app.digital_focus_value + 0.5;
            app.OffsetmEditField.Value = num2str(app.digital_focus_value);
        end

        % Value changing function: OffsetmEditField
        function OffsetmEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
            
        end

        % Value changed function: OffsetmEditField
        function OffsetmEditFieldValueChanged(app, event)
            value = app.OffsetmEditField.Value;
            
            if ~isnan(str2double(value))
                app.digital_focus_value = str2double(value);
            else
                app.OffsetmEditField.Value = num2str(app.digital_focus_value);
            end
        end

        % Button pushed function: QuickRefocusPreviewButton
        function QuickRefocusPreviewButtonPushed(app, event)
            
            prompt = {'Digital Refocusing Step Size:'};
            dlgtitle = 'Input';
            dims = [1];
            definput = {'5'};
            
            step_size= inputdlg(prompt,dlgtitle,dims,definput);
            
            if isempty(step_size)
                return
            end
            
            step_size = str2num(step_size{1});
            
            if app.live_recon_control ~= 1
                return
            end
            
            frame = getsnapshot(app.my_camera.vid);
                
            try
                gpuDevice;
                frame = gpuArray(frame);
            catch ME
                warndlg("GPU not enabled.");
            end
            app.capture_roi = [1 1 size(frame,2) size(frame, 1)];
            
            frame_cropped = imcrop(frame, [app.capture_roi(1) app.capture_roi(2) app.capture_roi(3) app.capture_roi(4)]);
            
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, -3*step_size);
            
            x = linspace(0,10,50);
            y1 = sin(x);
            y2 = rand(50,1);
            
            tiledlayout(2,4)
            
            % Z = -3 steps
            nexttile
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, -3*step_size);
            image(unwrapped_phase);
            step = -3*step_size;
            title("Distance: " + step + "um");
            
            % Z = -2 steps
            nexttile
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, -2*step_size);
            image(unwrapped_phase);
            step = -2*step_size;
            title("Distance: " + step + "um");
            
            % Z = -1 steps
            nexttile
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, -1*step_size);
            image(unwrapped_phase);
            step = -1*step_size;
            title("Distance: " + step + "um");
            % Z = 0 steps
            nexttile
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, 0*step_size);
            image(unwrapped_phase);
            step = 0*step_size;
            title("Distance: " + step + "um");
            
            % Z = 1 steps
            nexttile
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, 1*step_size);
            image(unwrapped_phase);
            step = 1*step_size;
            title("Distance: " + step + "um");
            % Z = 2 steps
            nexttile
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, 2*step_size);
            image(unwrapped_phase);
            step = 2*step_size;
            title("Distance: " + step + "um");
            % Z = 3 steps
            nexttile
            [app.capture_reconstructor, unwrapped_phase] = app.capture_reconstructor.preview(frame_cropped, app.first_order,0,app.MaxPhaseHeightInput.Value, app.live_preview_wavelength, app.live_preview_pixel_size, 3*step_size);
            image(unwrapped_phase);
            step = 3*step_size;
            title("Distance: " + step + "um");
        end

        % Value changed function: RefocusOffsetmEditField
        function RefocusOffsetmEditFieldValueChanged(app, event)
            value = app.RefocusOffsetmEditField.Value;
            
            if ~isnan(str2double(value))
                app.processing_digital_focus_value = str2double(value);
            else
                app.RefocusOffsetmEditField.Value = num2str(app.processing_digital_focus_value);
            end
        end

        % Value changed function: FrameGrabIntervalEditField
        function FrameGrabIntervalEditFieldValueChanged(app, event)
            value = app.FrameGrabIntervalEditField.Value;
            app.frame_grab_interval = value;
            %if ~isnan(str2double(value))
            %    app.frame_grab_interval =  round(str2double(value));
            %else
            %    app.FrameGrabIntervalEditField.Value = num2str(app.frame_grab_interval);
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DHMGUIUIFigure = uifigure('Visible', 'off');
            app.DHMGUIUIFigure.Name = 'DHM GUI V4.1';
            app.DHMGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @DHMGUIUIFigureCloseRequest, true);
            app.Panel = uipanel(app.DHMGUIUIFigure);
            app.ImagingLabel = uilabel(app.Panel);
            app.ImagingLabel.Text = 'Imaging';
            app.SelectRegionofInterestButton = uibutton(app.Panel, 'push');
            app.SelectRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionofInterestButtonPushed, true);
            app.SelectRegionofInterestButton.Enable = 'off';
            app.SelectRegionofInterestButton.Text = {'Select Region of'; 'Interest'};
            app.ResetRegionofInterestButton = uibutton(app.Panel, 'push');
            app.ResetRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRegionofInterestButtonPushed, true);
            app.ResetRegionofInterestButton.Enable = 'off';
            app.ResetRegionofInterestButton.Text = {'Reset Region of '; 'Interest'};
            app.StartCameraPreviewButton = uibutton(app.Panel, 'push');
            app.StartCameraPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraPreviewButtonPushed, true);
            app.StartCameraPreviewButton.Text = 'Start Camera Preview';
            app.RecordHologramButton = uibutton(app.Panel, 'push');
            app.RecordHologramButton.ButtonPushedFcn = createCallbackFcn(app, @RecordHologramButtonPushed, true);
            app.RecordHologramButton.Enable = 'off';
            app.RecordHologramButton.Text = 'Record Hologram';
            app.PhasePreviewButton = uibutton(app.Panel, 'push');
            app.PhasePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PhasePreviewButtonPushed, true);
            app.PhasePreviewButton.Enable = 'off';
            app.PhasePreviewButton.Text = 'Phase Preview';
            app.CaptureSingleFrameButton = uibutton(app.Panel, 'push');
            app.CaptureSingleFrameButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureSingleFrameButtonPushed, true);
            app.CaptureSingleFrameButton.Enable = 'off';
            app.CaptureSingleFrameButton.Text = {'Capture Single Frame'; ''};
            app.ApplyChangesButton = uibutton(app.Panel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Enable = 'off';
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ExposuresEditFieldLabel = uilabel(app.Panel);
            app.ExposuresEditFieldLabel.Text = 'Exposure (μs)';
            app.ExposuresEditField = uieditfield(app.Panel, 'numeric');
            app.ExposuresEditField.Limits = [12 15003];
            app.ExposuresEditField.ValueDisplayFormat = '%.0f';
            app.ExposuresEditField.ValueChangedFcn = createCallbackFcn(app, @ExposuresEditFieldValueChanged, true);
            app.ExposuresEditField.Value = 8000;
            app.FramerateEditFieldLabel = uilabel(app.Panel);
            app.FramerateEditFieldLabel.Text = 'Framerate';
            app.FramerateEditField = uieditfield(app.Panel, 'numeric');
            app.FramerateEditField.Limits = [0 Inf];
            app.FramerateEditField.ValueDisplayFormat = '%.0f';
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @FramerateEditFieldValueChanged, true);
            app.FramerateEditField.Value = 10;
            app.GainEditFieldLabel = uilabel(app.Panel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.Panel, 'numeric');
            app.GainEditField.Limits = [0 Inf];
            app.GainEditField.ValueDisplayFormat = '%.0f';
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.CameraDropDownLabel = uilabel(app.Panel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.Panel);
            app.CameraDropDown.Items = {'blackfly_s', 'pixelfly'};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = 'blackfly_s';
            app.RecordPhaseButton = uibutton(app.Panel, 'push');
            app.RecordPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @RecordPhaseButtonPushed, true);
            app.RecordPhaseButton.Enable = 'off';
            app.RecordPhaseButton.Text = 'Record Phase';
            app.SettingsLabel = uilabel(app.Panel);
            app.SettingsLabel.Text = 'Settings';
            app.RecordingLabel = uilabel(app.Panel);
            app.RecordingLabel.Text = 'Recording';
            app.RefracIndexDiffEditField_2Label = uilabel(app.Panel);
            app.RefracIndexDiffEditField_2Label.Text = 'Refrac. Index Diff.';
            app.RefracIndexDiffEditFieldImaging = uieditfield(app.Panel, 'numeric');
            app.RefracIndexDiffEditFieldImaging.Limits = [0 1];
            app.RefracIndexDiffEditFieldImaging.Value = 0.06;
            app.WavelengthnmEditField_2Label = uilabel(app.Panel);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength (nm)';
            app.WavelengthnmEditFieldImaging = uieditfield(app.Panel, 'numeric');
            app.WavelengthnmEditFieldImaging.Limits = [1 Inf];
            app.WavelengthnmEditFieldImaging.Value = 514;
            app.LinePlotButton = uibutton(app.Panel, 'push');
            app.LinePlotButton.ButtonPushedFcn = createCallbackFcn(app, @LinePlotButtonPushed, true);
            app.LinePlotButton.Enable = 'off';
            app.LinePlotButton.Text = {'Line Plot'; ''};
            app.MaximumPhasemLabel = uilabel(app.Panel);
            app.MaximumPhasemLabel.Text = 'Maximum Phase (μm)';
            app.MaxPhaseHeightInput = uieditfield(app.Panel, 'numeric');
            app.MaxPhaseHeightInput.Limits = [1 Inf];
            app.MaxPhaseHeightInput.Value = 20;
            app.DigitalRefocusLabel = uilabel(app.Panel);
            app.DigitalRefocusLabel.Text = 'Digital Refocus';
            app.OffsetmEditFieldLabel = uilabel(app.Panel);
            app.OffsetmEditFieldLabel.Text = 'Offset (μm)';
            app.OffsetmEditField = uieditfield(app.Panel, 'text');
            app.OffsetmEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetmEditFieldValueChanged, true);
            app.OffsetmEditField.ValueChangingFcn = createCallbackFcn(app, @OffsetmEditFieldValueChanging, true);
            app.OffsetmEditField.Value = '0';
            app.DecreaseDigitalFocus = uibutton(app.Panel, 'push');
            app.DecreaseDigitalFocus.ButtonPushedFcn = createCallbackFcn(app, @DecreaseDigitalFocusPushed, true);
            app.DecreaseDigitalFocus.Text = '-';
            app.IncreaseDigitalFocus = uibutton(app.Panel, 'push');
            app.IncreaseDigitalFocus.ButtonPushedFcn = createCallbackFcn(app, @IncreaseDigitalFocusButtonPushed, true);
            app.IncreaseDigitalFocus.Text = '+';
            app.PixelSizemLabel = uilabel(app.Panel);
            app.PixelSizemLabel.Text = 'Pixel Size(μm)';
            app.PixelSizeInput = uieditfield(app.Panel, 'numeric');
            app.PixelSizeInput.Limits = [0 Inf];
            app.PixelSizeInput.Value = 0.176;
            app.QuickRefocusPreviewButton = uibutton(app.Panel, 'push');
            app.QuickRefocusPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @QuickRefocusPreviewButtonPushed, true);
            app.QuickRefocusPreviewButton.Text = 'Quick Refocus Preview';
            app.FrameGrabIntervalEditFieldLabel = uilabel(app.Panel);
            app.FrameGrabIntervalEditFieldLabel.Text = 'Frame Grab Interval';
            app.FrameGrabIntervalEditField = uieditfield(app.Panel, 'numeric');
            app.FrameGrabIntervalEditField.Limits = [1 Inf];
            app.FrameGrabIntervalEditField.RoundFractionalValues = 'on';
            app.FrameGrabIntervalEditField.ValueDisplayFormat = '%.0f';
            app.FrameGrabIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @FrameGrabIntervalEditFieldValueChanged, true);
            app.FrameGrabIntervalEditField.Value = 1;
            app.Label = uilabel(app.DHMGUIUIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.version = uitextarea(app.DHMGUIUIFigure);
            app.version.Editable = 'off';
            app.version.Enable = 'off';
            app.version.Value = {'DHM GUI with Blackfly S V4.1'};
            app.Panel_2 = uipanel(app.DHMGUIUIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.PreviewButton = uibutton(app.Panel_2, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.LoadImageFileButton = uibutton(app.Panel_2, 'push');
            app.LoadImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageFileButtonPushed, true);
            app.LoadImageFileButton.Text = 'Load Image/File';
            app.SetRegionofInterestButton = uibutton(app.Panel_2, 'push');
            app.SetRegionofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @SetRegionofInterestButtonPushed, true);
            app.SetRegionofInterestButton.Text = {'Set Region'; 'of Interest'};
            app.ProcessingLabel = uilabel(app.Panel_2);
            app.ProcessingLabel.Text = 'Processing';
            app.SelectFolderButton = uibutton(app.Panel_2, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.LowerLimitCheckBox = uicheckbox(app.Panel_2);
            app.LowerLimitCheckBox.Text = 'Lower Limit';
            app.LowerLimitCheckBox.Value = true;
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea.Editable = 'off';
            app.OutputFolderEditField = uieditfield(app.Panel_2, 'text');
            app.OutputFolderEditField.Editable = 'off';
            app.ProcessButton = uibutton(app.Panel_2, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.SummaryCheckBox = uicheckbox(app.Panel_2);
            app.SummaryCheckBox.Text = 'Summary';
            app.SummaryCheckBox.Value = true;
            app.SelectOutputButton = uibutton(app.Panel_2, 'push');
            app.SelectOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputButtonPushed, true);
            app.SelectOutputButton.Text = 'Select';
            app.OutputFolderLabel = uilabel(app.Panel_2);
            app.OutputFolderLabel.Text = 'Output Folder';
            app.UpperLimitEditFieldLabel = uilabel(app.Panel_2);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.Panel_2, 'numeric');
            app.UpperLimitEditField.Limits = [0 Inf];
            app.UpperLimitEditField.Value = 20;
            app.PixelSizemEditFieldLabel = uilabel(app.Panel_2);
            app.PixelSizemEditFieldLabel.Text = 'Pixel Size (μm)';
            app.PixelSizemEditField = uieditfield(app.Panel_2, 'numeric');
            app.PixelSizemEditField.Limits = [0 Inf];
            app.PixelSizemEditField.Value = 0.176;
            app.WavelengthnmEditFieldLabel = uilabel(app.Panel_2);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.Panel_2, 'numeric');
            app.WavelengthnmEditField.Limits = [1 Inf];
            app.WavelengthnmEditField.Value = 514;
            app.RefracIndexDiffEditFieldLabel = uilabel(app.Panel_2);
            app.RefracIndexDiffEditFieldLabel.Text = 'Refrac. Index Diff.';
            app.RefracIndexDiffEditField = uieditfield(app.Panel_2, 'numeric');
            app.RefracIndexDiffEditField.Limits = [0 1];
            app.RefracIndexDiffEditField.Value = 0.06;
            app.OutputOptionsLabel = uilabel(app.Panel_2);
            app.OutputOptionsLabel.Text = 'Output Options';
            app.HologramCheckBox = uicheckbox(app.Panel_2);
            app.HologramCheckBox.Text = 'Hologram';
            app.HologramCheckBox.Value = true;
            app.QuantitativeHeightCheckBox = uicheckbox(app.Panel_2);
            app.QuantitativeHeightCheckBox.Text = 'Quantitative Height';
            app.QuantitativeHeightCheckBox.Value = true;
            app.HeightMeshCheckBox = uicheckbox(app.Panel_2);
            app.HeightMeshCheckBox.Text = 'Height Mesh';
            app.HeightMeshCheckBox.Value = true;
            app.BrightfieldCheckBox = uicheckbox(app.Panel_2);
            app.BrightfieldCheckBox.Text = 'Brightfield';
            app.BrightfieldCheckBox.Value = true;
            app.Panel_3 = uipanel(app.Panel_2);
            app.ConverttoimagesButton = uibutton(app.Panel_3, 'push');
            app.ConverttoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoimagesButtonPushed, true);
            app.ConverttoimagesButton.Enable = 'off';
            app.ConverttoimagesButton.Text = 'Convert to images';
            app.FrameSkipEditFieldLabel = uilabel(app.Panel_3);
            app.FrameSkipEditFieldLabel.Enable = 'off';
            app.FrameSkipEditFieldLabel.Text = 'Frame Skip';
            app.FrameSkipEditField = uieditfield(app.Panel_3, 'numeric');
            app.FrameSkipEditField.Limits = [1 Inf];
            app.FrameSkipEditField.Enable = 'off';
            app.FrameSkipEditField.Value = 1;
            app.EndsecEditFieldLabel = uilabel(app.Panel_3);
            app.EndsecEditFieldLabel.Enable = 'off';
            app.EndsecEditFieldLabel.Text = 'End (sec)';
            app.EndsecEditField = uieditfield(app.Panel_3, 'numeric');
            app.EndsecEditField.Limits = [0 Inf];
            app.EndsecEditField.Enable = 'off';
            app.StartsecEditFieldLabel = uilabel(app.Panel_3);
            app.StartsecEditFieldLabel.Enable = 'off';
            app.StartsecEditFieldLabel.Text = 'Start (sec)';
            app.StartsecEditField = uieditfield(app.Panel_3, 'numeric');
            app.StartsecEditField.Limits = [0 Inf];
            app.StartsecEditField.Enable = 'off';
            app.FFTFirstOrderSelectionSwitchLabel = uilabel(app.Panel_2);
            app.FFTFirstOrderSelectionSwitchLabel.Text = 'FFT First Order Selection';
            app.FFTFirstOrderSelectionSwitch = uiswitch(app.Panel_2, 'slider');
            app.FFTFirstOrderSelectionSwitch.Items = {'Auto', 'Manual'};
            app.FFTFirstOrderSelectionSwitch.Value = 'Manual';
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Blue_question_mark_icon.svg';
            app.CroppedRegionCheckBox = uicheckbox(app.Panel_2);
            app.CroppedRegionCheckBox.Text = 'Cropped Region';
            app.CroppedRegionCheckBox.Value = true;
            app.RefocusOffsetmEditFieldLabel = uilabel(app.Panel_2);
            app.RefocusOffsetmEditFieldLabel.Text = 'Refocus Offset (μm)';
            app.RefocusOffsetmEditField = uieditfield(app.Panel_2, 'numeric');
            app.RefocusOffsetmEditField.ValueChangedFcn = createCallbackFcn(app, @RefocusOffsetmEditFieldValueChanged, true);
            app.HelpSupportButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.HelpSupportButton.ButtonPushedFcn = createCallbackFcn(app, @HelpSupportButtonPushed, true);
            app.HelpSupportButton.Text = 'Help/Support';
            % Show the figure after all components are created
            app.DHMGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_ui_combined_input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DHMGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DHMGUIUIFigure)
        end
    end
end