classdef test_ui_new_naming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DHMGUIUIFigure                  matlab.ui.Figure
        NoiseLevelEditFieldLabel        matlab.ui.control.Label
        NoiseLevelEditField             matlab.ui.control.NumericEditField
        RunAutoButton                   matlab.ui.control.Button
        RunManualButton                 matlab.ui.control.Button
        ManualFrequencyCroppingLabel    matlab.ui.control.Label
        AutoFrequencyCroppingLabel      matlab.ui.control.Label
        FileSaveNameEditFieldLabel      matlab.ui.control.Label
        FileSaveNameEditField           matlab.ui.control.EditField
        ImagingLabel                    matlab.ui.control.Label
        SingleFrameReconstructionLabel  matlab.ui.control.Label
        ROISelectionLabel               matlab.ui.control.Label
        ConvertButton                   matlab.ui.control.Button
        UpperLeftXEditFieldLabel        matlab.ui.control.Label
        UpperLeftXEditField             matlab.ui.control.NumericEditField
        UpperLeftYEditFieldLabel        matlab.ui.control.Label
        UpperLeftYEditField             matlab.ui.control.NumericEditField
        FrameWidthEditFieldLabel        matlab.ui.control.Label
        FrameWidthEditField             matlab.ui.control.NumericEditField
        FrameHeightEditFieldLabel       matlab.ui.control.Label
        FrameHeightEditField            matlab.ui.control.NumericEditField
        FrameIntevalEditFieldLabel      matlab.ui.control.Label
        FrameIntevalEditField           matlab.ui.control.NumericEditField
        StartTimesecEditFieldLabel      matlab.ui.control.Label
        StartTimesecEditField           matlab.ui.control.NumericEditField
        EndTimesecEditFieldLabel        matlab.ui.control.Label
        EndTimesecEditField             matlab.ui.control.NumericEditField
        RunBulkButton                   matlab.ui.control.Button
        BatchFrameReconstructionLabel   matlab.ui.control.Label
        StartPreviewButton              matlab.ui.control.Button
        StartRecordButton               matlab.ui.control.Button
        StopRecordButton                matlab.ui.control.Button
        StopPreviewButton               matlab.ui.control.Button
        NoiseLevelEditField_2Label      matlab.ui.control.Label
        NoiseLevelEditField_2           matlab.ui.control.NumericEditField
        RunCombineButton                matlab.ui.control.Button
        SaveFolderEditFieldLabel        matlab.ui.control.Label
        SaveFolderEditField             matlab.ui.control.EditField
        SaveFolderEditField_2Label      matlab.ui.control.Label
        SaveFolderEditField_2           matlab.ui.control.EditField
        ComninedSaveFolderEditFieldLabel  matlab.ui.control.Label
        ComninedSaveFolderEditField     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        PreviewButton                   matlab.ui.control.Button
        SelectImageButton               matlab.ui.control.Button
        ImagePathTextArea               matlab.ui.control.TextArea
        SelectVideoButton               matlab.ui.control.Button
        ImagePathTextArea_2             matlab.ui.control.TextArea
        ImageFolderButton               matlab.ui.control.Button
        ImagePathTextArea_3             matlab.ui.control.TextArea
        CommonDirectoryButton           matlab.ui.control.Button
        ImagePathTextArea_4             matlab.ui.control.TextArea
        PreviewCroppingButton           matlab.ui.control.Button
        CalculationParametersLabel      matlab.ui.control.Label
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        RefracIndexDiffEditFieldLabel   matlab.ui.control.Label
        RefracIndexDiffEditField        matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        InvertZaxisCheckBox             matlab.ui.control.CheckBox
        SaveHeightCheckBox              matlab.ui.control.CheckBox
        SaveVolumeCheckBox              matlab.ui.control.CheckBox
        LowerLimitCheckBox              matlab.ui.control.CheckBox
        InvertZaxisCheckBox_2           matlab.ui.control.CheckBox
        LowerLimitCheckBox_2            matlab.ui.control.CheckBox
        UpperLimitEditField_2Label      matlab.ui.control.Label
        UpperLimitEditField_2           matlab.ui.control.NumericEditField
        PixelSizemEditFieldLabel        matlab.ui.control.Label
        PixelSizemEditField             matlab.ui.control.NumericEditField
        ApplyChangesButton              matlab.ui.control.Button
        ExposuresEditField_2Label       matlab.ui.control.Label
        ExposuresEditField              matlab.ui.control.NumericEditField
        CameraModeDropDownLabel         matlab.ui.control.Label
        CameraModeDropDown              matlab.ui.control.DropDown
        FramerateLiveEditFieldLabel     matlab.ui.control.Label
        FramerateLiveEditField          matlab.ui.control.NumericEditField
        FramerateSaveEditFieldLabel     matlab.ui.control.Label
        FramerateSaveEditField          matlab.ui.control.NumericEditField
        ROISelectButton                 matlab.ui.control.Button
        ROIResetButton                  matlab.ui.control.Button
        ShowFFTButton                   matlab.ui.control.Button
        CloseFFTButton                  matlab.ui.control.Button
        LiveReconButton                 matlab.ui.control.Button
        CloseReconButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        mode
        vid         % video object
        diskLogger % disk object
        src         % record object
        exp_time    % exposure time
        path        % file name
        fft_check_control
        live_recon_control
                
        image_path  % single image selection
        video_path  % video selection
        image_folder_path   % image folder selection
        common_directory    % common directory for figure combine
        desktop_path        % system desktop directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [~, app.desktop_path] = system('echo %USERPROFILE%');
            app.desktop_path = strcat(app.desktop_path, '\Desktop\');
        end

        % Button pushed function: RunManualButton
        function RunManualButtonPushed(app, event)
            tic;    
        
            imgo = imread(app.image_path); % open hologram
            [~, ~, img_dim] = size(imgo);
            if img_dim ~= 1
                img = double(rgb2gray(imgo)); % double format
            else
                img = double(imgo);
            end
            fftimg = fftshift(fft2(img)); % 2d fourier transform and translate to centre
            imgsize = size(fftimg); %determine image size
            logimg = log(abs(fftimg)); % for illustration purpose only?
            logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
            figure('name', 'Manual FFT Cropping');
            imshow(logimgmaxmin);
            roi_manual = drawrectangle;
            first_order = floor(roi_manual.Position);
            close 'Manual FFT Cropping';
            
            fftimgcrop = fftimg(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)); % locate first order
            centreimg = zeros(imgsize(1), imgsize(2)); % create a black image for placing first order
            centreimg(floor(imgsize(1)/2):floor(imgsize(1)/2)+first_order(4), floor(imgsize(2)/2):floor(imgsize(2)/2)+first_order(3)) = fftimgcrop; % place first order at centre
            
            % reconstruct = ifft2(fftshift(centreimg)); % inverse fourier transform
            reconstructed = ifft2(ifftshift(centreimg));
            intensity = abs(reconstructed); % intensity
            phase = angle(reconstructed); % phase
            
            % unwrap
            phase_unwrap = double(Miguel_2D_unwrapper(single(phase)));
            
%             % resize image by 10 pixel
%             resize = 1;
%             intensity = intensity(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
%             % phase = phase(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
%             phase_unwrap = phase_unwrap(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
            
            if app.InvertZaxisCheckBox_2.Value == 1
                phase_unwrap = - phase_unwrap;
            end
                        
            % curve removal
            curve_phase = curve(phase_unwrap);
            curve_intensity = curve(intensity);
            phase_unwrap_no_curve = (phase_unwrap - curve_phase);
            intensity_no_curve = intensity - curve_intensity;
            
            % thickness calculation
            wavelength = app.WavelengthnmEditField.Value * 10^(-3);
            refractive_index_diff = app.RefracIndexDiffEditField.Value;
            factor = wavelength/(2*pi*refractive_index_diff);
            thickness = (factor * phase_unwrap_no_curve)';
            
            % caution!!! set negative thickness to 0
            if app.LowerLimitCheckBox_2.Value == 1
                thickness(thickness < 0) = 0;
            end
            
            % apply median filter for 5 neighbouring pixels
            % thickness = medfilt2(thickness, [5, 5]);
            
            % apply weighted moving average filter for 5 neighbouring
            % pixels using conv2
            conv_mask = ones(5, 5) / 5^2;
            thickness = conv2(thickness, conv_mask, 'same');
            
            % resize thickness based on pixel size
            thickness = imresize(thickness, app.PixelSizemEditField.Value);
            
            % highlight spatial region
            show_crop_region = logimgmaxmin;
            show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) = ...
                show_crop_region(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)) + 0.2;
            
            t = toc;
            app.ImagePathTextArea.Value = ['Done! Manual cropping performed.' newline 'Total time: ' num2str(t) 's'];
            
            % Upper/lower colorbar limit
            if app.UpperLimitEditField_2.Value == 0
                upper_limit = max(thickness, [], 'all');
            else
                upper_limit = app.UpperLimitEditField_2.Value;
            end
            
            lower_limit = min(thickness, [], 'all');
            
            % display intensity, phase image and spatial frequency, no unwrapping
%             figure('name', 'Manual Cropping');
%             subplot(3, 3, 1), imagesc(imgo); title('Hologram');
%             subplot(3, 3, 2), imagesc(intensity); title('Intensity'); colorbar;
%             subplot(3, 3, 3), imagesc(intensity_no_curve); title('Intensity Curve Removed'); colorbar;
%             subplot(3, 3, 4), imagesc(phase); title('Phase'); colorbar;
%             subplot(3, 3, 5), imagesc(phase_unwrap); title('Phase Unwrapped'); colorbar;
%             subplot(3, 3, 6), imagesc(phase_unwrap_no_curve); title('Phase Unwrapped Curve Removed'); colorbar;
%             subplot(3, 3, 7), imagesc(show_crop_region); title('Spatial Frequency Cropping'); colorbar;
%             subplot(3, 3, 8), imagesc(thickness); title('Thickness'); colorbar;
%             subplot(3, 3, 9), mesh(thickness); view(-100,50); title('Thickness'); colorbar;
            figure('name', 'Manual Cropping');
            subplot(3, 2, 1), imagesc(imgo); title('Hologram');
            subplot(3, 2, 2), imagesc(show_crop_region); title('Spatial Frequency Cropping'); colorbar;
            subplot(3, 2, 3), imagesc(intensity_no_curve); title('Intensity'); colorbar;
            subplot(3, 2, 4), imagesc(phase_unwrap_no_curve); title('Phase'); colorbar;
            subplot(3, 2, 5), imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
            subplot(3, 2, 6), mesh(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
        end

        % Button pushed function: RunAutoButton
        function RunAutoButtonPushed(app, event)
            tic;
            
            imgo = imread(app.image_path); % open hologram
            [~, ~, img_dim] = size(imgo);
            if img_dim ~= 1
                img = double(rgb2gray(imgo)); % double format
            else
                img = double(imgo);
            end
            fftimg = fftshift(fft2(img)); % 2d fourier transform and translate to centre
            imgsize = size(fftimg); %determine image size
            logimg = log(abs(fftimg)); % for illustration purpose only?
            logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
            % logimgplus1maxmin = (logimgplus1 - min(min(logimgplus1)))/(max(max(logimgplus1))-min(min(logimgplus1))); % normalise
            % logimgplus1 = log(abs(fftimg)+1); % increase contrast?
            
            % fft image gaussian fit and get global threshold level
            sigma = 4;
            logimg_filtered = imgaussfilt(logimgmaxmin, sigma);
            global_thresh = graythresh(logimg_filtered);
            
            % seperate noise region and order region
            small_region = round((imgsize(1)/app.NoiseLevelEditField.Value) * (imgsize(2)/app.NoiseLevelEditField.Value)); % noise
            big_region = round((imgsize(1)/2) * (imgsize(2)/2));
            
            % threshold controls
            num_object = 1;
            thresh_increment = 0;
            thresh_step = global_thresh/50;
            
            % threshold loop
            while num_object ~= 3
                bw = imbinarize(logimg_filtered, global_thresh + thresh_increment);
                bw_denoise = bwareaopen(bw, small_region); % remove noise region
                bw_prop = regionprops(bw_denoise); % get property of the only bright region
                num_object = size(bw_prop);
                if num_object == 3
                    areas = [bw_prop(1, 1).Area, bw_prop(2, 1).Area, bw_prop(2, 1).Area];
                    if (max(areas) > big_region) || (min(areas) < small_region)
                        num_object = 1;
                    end
                end
                thresh_increment = thresh_increment + thresh_step;
            end
            
            % get coordinates of region boundary
            % boundaries = bwboundaries(bw_denoise);
            
            % get region centroid and compare positions
            pos_1 = bw_prop(1).Centroid;
            pos_2 = bw_prop(2).Centroid;
            pos_3 = bw_prop(3).Centroid;
            cen_1 = abs(pos_1(1)) - abs(pos_1(2));
            cen_2 = abs(pos_2(1)) - abs(pos_2(2));
            cen_3 = abs(pos_3(1)) - abs(pos_3(2));
            [~, index] = max([cen_1, cen_2, cen_3]);
            
            % setup region box
            region_pos = bw_prop(index).Centroid;
            region_box = bw_prop(index).BoundingBox;
            % region_boundary = boundaries{index};
            
            region_half_x = abs(region_box(1) - region_pos(1));
            region_half_y = abs(region_box(2) - region_pos(2));
            region_half_acu_x = region_box(3)/2;
            region_half_acu_y = region_box(4)/2;
            
            % modify region box accoridng to relative centroid position
            if region_half_x > region_half_acu_x
                region_box(3) = region_half_x * 2;
            else
                region_box(1) = region_pos(1) - region_half_x; % no change to upper left x?
            end
            
            if region_half_y > region_half_acu_y
                region_box(4) = region_half_y * 2;
            else
                region_box(2) = region_pos(2) - region_half_y; % no change to upper left y?
            end
            
            % enlarge region box according to threshold increment?
            thresh_increment_amount = thresh_increment * 0;
            region_box(1:2) = region_box(1:2) - region_box(3:4) * (thresh_increment_amount/2);
            region_box(3:4) = region_box(3:4) + region_box(3:4) * (thresh_increment_amount);
            region_box = floor(region_box);
            
            % create filter mask based on selected region
            if region_box(2) + region_box(4) > imgsize(1)
                region_box(4) = imgsize(1) - region_box(2);
            end
            if region_box(1) + region_box(3) > imgsize(2)
                region_box(3) = imgsize(2) - region_box(1);
            end
            if region_box(1) == 0
                region_box(1) = 1;
            end
            if region_box(2) == 0
                region_box(2) = 1;
            end
            
            mask = zeros(imgsize(1), imgsize(2));
            mask(   region_box(2) : (region_box(2) + region_box(4)), ...
                region_box(1) : (region_box(1) + region_box(3))) = 1;
            
            % apply mask
            % logimgmaxmin_masked = logimgmaxmin .* mask;
            fftimg_masked = fftimg .* mask;
            
            % transfering fft region
            centreimg = zeros(imgsize(1), imgsize(2)); % create a black image for placing first order;
            region_box_dummy = region_box;
            region_box_dummy(1) = round(imgsize(2)/2 - abs(region_pos(1) - region_box_dummy(1)));
            region_box_dummy(2) = round(imgsize(1)/2 - abs(region_pos(2) - region_box_dummy(2)));
            
            window = fftimg_masked( region_box(2) : (region_box(2) + region_box(4)), ...
                region_box(1) : (region_box(1) + region_box(3)));
            centreimg(   region_box_dummy(2) : (region_box_dummy(2) + region_box_dummy(4)), ...
                region_box_dummy(1) : (region_box_dummy(1) + region_box_dummy(3))) = window;
            
            % % illustration of mask on fft image
            % window_illustration = logimgmaxmin_masked(  region_box(2) : (region_box(2) + region_box(4)), ...
            %                                             region_box(1) : (region_box(1) + region_box(3)));
            % logimgmaxmin_masked_windowed = zeros(imgsize(1), imgsize(2));
            % logimgmaxmin_masked_windowed(   region_box_dummy(2) : (region_box_dummy(2) + region_box_dummy(4)), ...
            %                                 region_box_dummy(1) : (region_box_dummy(1) + region_box_dummy(3))) = window_illustration;
            
            % reconstruction
            reconstructed = ifft2(ifftshift(centreimg));
            intensity = abs(reconstructed); % intensity
            phase = angle(reconstructed); % phase
            
            % unwrap
            phase_unwrap = double(Miguel_2D_unwrapper(single(phase)));
            
%             % resize image by 10 pixel
%             resize = 1;
%             intensity = intensity(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
%             % phase = phase(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
%             phase_unwrap = phase_unwrap(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
            
            if app.InvertZaxisCheckBox_2.Value == 1
                phase_unwrap = - phase_unwrap;
            end
            
            % curve removal
            curve_phase = curve(phase_unwrap);
            curve_intensity = curve(intensity);
            phase_unwrap_no_curve = (phase_unwrap - curve_phase);
            intensity_no_curve = intensity - curve_intensity;
            
            % thickness calculation
            wavelength = app.WavelengthnmEditField.Value * 10^(-3);
            refractive_index_diff = app.RefracIndexDiffEditField.Value;
            factor = wavelength/(2*pi*refractive_index_diff);
            thickness = (factor * phase_unwrap_no_curve)';
            
            % caution!!! set negative thickness to 0
            if app.LowerLimitCheckBox_2.Value == 1
                thickness(thickness < 0) = 0;
            end
            
            % apply median filter for 5 neighbouring pixels
            % thickness = medfilt2(thickness, [5, 5]);
            
            % apply weighted moving average filter for 5 neighbouring
            % pixels using conv2
            conv_mask = ones(5, 5) / 5^2;
            thickness = conv2(thickness, conv_mask, 'same');
            
            % resize thickness based on pixel size
            thickness = imresize(thickness, app.PixelSizemEditField.Value);
            
            % highlight spatial region
            % show_crop_region = logimgmaxmin_masked;
            
            dimmed_fftimg = logimgmaxmin;
            dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                region_box(1) : (region_box(1) + region_box(3))) = ...
                dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                region_box(1) : (region_box(1) + region_box(3))) + 0.2;
            
            t = toc;
            app.ImagePathTextArea.Value = ['Done! Auto cropping performed.' newline 'Total time: ' num2str(t) 's'];
            
            % Upper/lower colorbar limit
            if app.UpperLimitEditField_2.Value == 0
                upper_limit = max(thickness, [], 'all');
            else
                upper_limit = app.UpperLimitEditField_2.Value;
            end
            
            lower_limit = min(thickness, [], 'all');
            
            % display intensity, phase image and spatial frequency, no unwrapping
%             figure('name', 'Auto Cropping');
%             subplot(3, 3, 1), imagesc(imgo); title('Hologram');
%             subplot(3, 3, 2), imagesc(intensity); title('Intensity'); colorbar;
%             subplot(3, 3, 3), imagesc(intensity_no_curve); title('Intensity Curve Removed'); colorbar;
%             subplot(3, 3, 4), imagesc(phase); title('Phase'); colorbar;
%             subplot(3, 3, 5), imagesc(phase_unwrap); title('Phase Unwrapped'); colorbar;
%             subplot(3, 3, 6), imagesc(phase_unwrap_no_curve); title('Phase Unwrapped Curve Removed'); colorbar;
%             subplot(3, 3, 7), imagesc(show_crop_region); title('Spatial Frequency Cropping'); colorbar;
%             subplot(3, 3, 8), imagesc(thickness); title('Thickness'); colorbar;
%             subplot(3, 3, 9), mesh(thickness); view(-100,50); title('Thickness'); colorbar;
            figure('name', 'Auto Cropping');
            subplot(3, 2, 1); imagesc(imgo); title('Hologram');
            subplot(3, 2, 2); imagesc(dimmed_fftimg); title('Spatial Frequency Cropping'); colorbar;
            subplot(3, 2, 3); imagesc(intensity_no_curve); title('Intensity'); colorbar;
            subplot(3, 2, 4); imagesc(phase_unwrap_no_curve); title('Phase'); colorbar;
            subplot(3, 2, 5); imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
            subplot(3, 2, 6); mesh(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            imaqreset;
            blackfly_s_cam = 'mwspinnakerimaq_r2019b';
            app.mode = app.CameraModeDropDown.Value; % Mono8; Mono10Packed; Mono12Packed
            
            % Mono16
            % Auto Framerate: 45
            % Exposure: 12 - 15003 us
            % Mono8
            % Auto Framerate: 60
            % Exposure: 12 - 15003 us
            % Mono10Packed
            % Auto Framerate: 60
            % Exposure: 12 - 15003 us
            % Mono12Packed
            % Auto Framerate: 60
            % Exposure: 12 - 15003 us
            
            app.vid = videoinput(blackfly_s_cam, 1, app.mode);
            app.vid.FramesPerTrigger = Inf;
            app.vid.ReturnedColorspace = 'grayscale';
            app.vid.LoggingMode = 'disk';
            app.vid.ROIPosition = [512 384 1024 768];
            triggerconfig(app.vid, 'immediate');
            
            time = datetime('now');
            
            app.diskLogger = VideoWriter(strcat(app.desktop_path, '_', string(time.Hour), '_', string(time.Minute), '_', app.FileSaveNameEditField.Value), 'Grayscale AVI');
            app.vid.DiskLogger = app.diskLogger;
            
            app.src = getselectedsource(app.vid);
            app.src.AdcBitDepth = 'Bit12';
            app.src.AcquisitionFrameRateEnable = 'True';
            if strcmp(app.mode, 'Mono16')
                app.src.AcquisitionFrameRate = 45;
            else
                app.src.AcquisitionFrameRate = 60;
            end
            app.diskLogger.FrameRate = app.src.AcquisitionFrameRate;
            app.src.ExposureAuto = 'Off';
            app.src.ExposureTime = app.ExposuresEditField.Value;    % 12 - 15003
            app.src.AutoAlgorithmSelector = 'Awb';
            app.src.GainAuto = 'Off';
            app.src.Gain = 0;
            app.src.GammaEnable = 'False';
            app.src.DefectCorrectStaticEnable = 'False';
            app.src.IspEnable = 'False';
            app.src.BlackLevelClampingEnable = 'False';
            app.src.BlackLevelSelector = 'All';
            app.src.BlackLevel = 0;
            app.src.CounterEventSource = 'Off';
            app.src.CounterTriggerSource = 'Off';
            app.src.DeviceIndicatorMode = 'ErrorStatus';
            app.src.DeviceLinkThroughputLimit = 500000000;
            app.src.ReverseX = 'False';
            app.src.ReverseY = 'False';
            
            preview(app.vid);
            app.FramerateLiveEditField.Value = app.src.AcquisitionResultingFrameRate;
            app.FramerateSaveEditField.Value = app.src.AcquisitionResultingFrameRate;
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)
            app.diskLogger.FrameRate = app.src.AcquisitionResultingFrameRate;
            start(app.vid);
        end

        % Button pushed function: StopRecordButton
        function StopRecordButtonPushed(app, event)
            stop(app.vid);
        end

        % Button pushed function: StopPreviewButton
        function StopPreviewButtonPushed(app, event)
            closepreview(app.vid);
            imaqreset;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            tic;
            
            video = VideoReader(app.video_path);
            if app.StartTimesecEditField.Value == 0
                start_frame = 1;
            else
                start_frame = app.StartTimesecEditField.Value * video.FrameRate;
            end
            if app.EndTimesecEditField.Value == 0
                end_frame = video.NumberOfFrames;
            elseif app.EndTimesecEditField.Value >= round(video.Duration - 0.5)
                end_frame = video.NumberOfFrames;
            else
                end_frame = app.EndTimesecEditField.Value * video.FrameRate;
            end
            
            x_start = app.UpperLeftXEditField.Value;
            y_start = app.UpperLeftYEditField.Value;
            width = app.FrameWidthEditField.Value;
            height = app.FrameHeightEditField.Value;
            
            line_width = 2;

            save_folder = strcat(app.desktop_path, app.SaveFolderEditField.Value);
            save_folder
            mkdir(save_folder);
            mkdir([save_folder '\cropped']);
            mkdir([save_folder '\original']);

            for count = start_frame : app.FrameIntevalEditField.Value : end_frame
                frame = read(video, count);
                img_original = frame;
                img_original(y_start : y_start + height - 1, x_start : x_start + line_width) = 255;
                img_original(y_start : y_start + height - 1, x_start + width - 1 - line_width : x_start + width - 1) = 255;
                img_original(y_start : y_start + line_width, x_start : x_start + width - 1) = 255;
                img_original(y_start + height - 1 - line_width : y_start + height - 1, x_start : x_start + width - 1) = 255;
                img_cropped = imcrop(frame, [x_start y_start width height]);
                imwrite(img_original, [save_folder '\original\' 'original_' int2str(count), '.tif']);
                imwrite(img_cropped, [save_folder '\cropped\' 'cropped_' int2str(count), '.tif']);
                
                % Too fast, can't show...
                % app.ImagePathTextArea_2.Value = ['Extracting frame: Frame_index_' int2str(count)];
            end
            
            t = toc;
            
            app.ImagePathTextArea_2.Value = ['Done! Total time: ' num2str(t) 's'];
        end

        % Button pushed function: RunBulkButton
        function RunBulkButtonPushed(app, event)
            tic;    
        
            input = app.image_folder_path;
            up = dir(strcat(input, '\*.tif'));
            
            save_folder = strcat(app.desktop_path, app.SaveFolderEditField_2.Value);
            mkdir(save_folder);
            mkdir([save_folder '\intensity']);
            mkdir([save_folder '\thickness']);
            mkdir([save_folder '\fft']);
            mkdir([save_folder '\mesh']);
            
            if app.SaveHeightCheckBox.Value == 1
                mkdir([save_folder '\thickness_data']);
            end
            
            if app.SaveVolumeCheckBox.Value == 1
                mkdir([save_folder '\volume_data']);
            end
            
            % peak height and volume for frames
            peak_height = [];
            volume = [];
            
            dim = [.2 .5 .3 .3];
            
            count = 1;
            
            for index = 1 : length(up)
                close all;
                file = up(index).name;
                file_dir = strcat(input, '\', file);
                
                imgo = imread(file_dir); % open hologram
                [~, ~, img_dim] = size(imgo);
                if img_dim ~= 1
                    img = double(rgb2gray(imgo)); % double format
                else
                    img = double(imgo);
                end
                fftimg = fftshift(fft2(img)); % 2d fourier transform and translate to centre
                imgsize = size(fftimg); %determine image size
                logimg = log(abs(fftimg)); % for illustration purpose only?
                logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
                % logimgplus1maxmin = (logimgplus1 - min(min(logimgplus1)))/(max(max(logimgplus1))-min(min(logimgplus1))); % normalise
                % logimgplus1 = log(abs(fftimg)+1); % increase contrast?
                
                % fft image gaussian fit and get global threshold level
                sigma = 4;
                logimg_filtered = imgaussfilt(logimgmaxmin, sigma);
                global_thresh = graythresh(logimg_filtered);
                
                % seperate noise region and order region
                small_region = round((imgsize(1)/app.NoiseLevelEditField_2.Value) * (imgsize(2)/app.NoiseLevelEditField_2.Value)); % noise
                big_region = round((imgsize(1)/2) * (imgsize(2)/2));
                
                % threshold controls
                num_object = 1;
                thresh_increment = 0;
                thresh_step = global_thresh/50;
                
                % threshold loop
                while num_object ~= 3
                    bw = imbinarize(logimg_filtered, global_thresh + thresh_increment);
                    bw_denoise = bwareaopen(bw, small_region); % remove noise region
                    bw_prop = regionprops(bw_denoise); % get property of the only bright region
                    num_object = size(bw_prop);
                    if num_object == 3
                        areas = [bw_prop(1, 1).Area, bw_prop(2, 1).Area, bw_prop(2, 1).Area];
                        if (max(areas) > big_region) || (min(areas) < small_region)
                            num_object = 1;
                        end
                    end
                    thresh_increment = thresh_increment + thresh_step;
                end
                
                % get coordinates of region boundary
                % boundaries = bwboundaries(bw_denoise);
                
                % get region centroid and compare positions
                pos_1 = bw_prop(1).Centroid;
                pos_2 = bw_prop(2).Centroid;
                pos_3 = bw_prop(3).Centroid;
                cen_1 = abs(pos_1(1)) - abs(pos_1(2));
                cen_2 = abs(pos_2(1)) - abs(pos_2(2));
                cen_3 = abs(pos_3(1)) - abs(pos_3(2));
                [~, index] = max([cen_1, cen_2, cen_3]);
                
                % setup region box
                region_pos = bw_prop(index).Centroid;
                region_box = bw_prop(index).BoundingBox;
                
                % region_boundary = boundaries{index};
                region_half_x = abs(region_box(1) - region_pos(1));
                region_half_y = abs(region_box(2) - region_pos(2));
                region_half_acu_x = region_box(3)/2;
                region_half_acu_y = region_box(4)/2;
                
                % modify region box accoridng to relative centroid position
                if region_half_x > region_half_acu_x
                    region_box(3) = region_half_x * 2;
                else
                    region_box(1) = region_pos(1) - region_half_x; % no change to upper left x?
                end
                if region_half_y > region_half_acu_y
                    region_box(4) = region_half_y * 2;
                else
                    region_box(2) = region_pos(2) - region_half_y; % no change to upper left y?
                end
                
                % enlarge region box according to threshold increment?
                thresh_increment_amount = thresh_increment * 0;
                region_box(1:2) = region_box(1:2) - region_box(3:4) * (thresh_increment_amount/2);
                region_box(3:4) = region_box(3:4) + region_box(3:4) * (thresh_increment_amount);
                region_box = floor(region_box);
                
                % create filter mask based on selected region
                if region_box(2) + region_box(4) > imgsize(1)
                    region_box(4) = imgsize(1) - region_box(2);
                end
                if region_box(1) + region_box(3) > imgsize(2)
                    region_box(3) = imgsize(2) - region_box(1);
                end
                if region_box(1) == 0
                    region_box(1) = 1;
                end
                if region_box(2) == 0
                    region_box(2) = 1;
                end
                
                mask = zeros(imgsize(1), imgsize(2));
                mask(   region_box(2) : (region_box(2) + region_box(4)), ...
                    region_box(1) : (region_box(1) + region_box(3))) = 1;
                % apply mask
                % logimgmaxmin_masked = logimgmaxmin .* mask;
                fftimg_masked = fftimg .* mask;
                
                % transfering fft region
                centreimg = zeros(imgsize(1), imgsize(2)); % create a black image for placing first order;
                region_box_dummy = region_box;
                region_box_dummy(1) = round(imgsize(2)/2 - abs(region_pos(1) - region_box_dummy(1)));
                region_box_dummy(2) = round(imgsize(1)/2 - abs(region_pos(2) - region_box_dummy(2)));
                window = fftimg_masked( region_box(2) : (region_box(2) + region_box(4)), ...
                    region_box(1) : (region_box(1) + region_box(3)));
                centreimg(   region_box_dummy(2) : (region_box_dummy(2) + region_box_dummy(4)), ...
                    region_box_dummy(1) : (region_box_dummy(1) + region_box_dummy(3))) = window;
                
                % % illustration of mask on fft image
                % window_illustration = logimgmaxmin_masked(  region_box(2) : (region_box(2) + region_box(4)), ...
                %                                             region_box(1) : (region_box(1) + region_box(3)));
                % logimgmaxmin_masked_windowed = zeros(imgsize(1), imgsize(2));
                % logimgmaxmin_masked_windowed(   region_box_dummy(2) : (region_box_dummy(2) + region_box_dummy(4)), ...
                %                                 region_box_dummy(1) : (region_box_dummy(1) + region_box_dummy(3))) = window_illustration;
                
                % reconstruction
                reconstructed = ifft2(ifftshift(centreimg));
                intensity = abs(reconstructed); % intensity
                phase = angle(reconstructed); % phase
                
                % unwrap
                phase_unwrap = double(Miguel_2D_unwrapper(single(phase)));
                
%                 % resize image by 10 pixel
%                 resize = 1;
%                 intensity = intensity(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
%                 % phase = phase(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
%                 phase_unwrap = phase_unwrap(resize:(imgsize(1)-resize), resize:(imgsize(2)-resize));
                
                if app.InvertZaxisCheckBox.Value == 1
                    phase_unwrap = - phase_unwrap;
                end
                
                % curve removal
                curve_phase = curve(phase_unwrap);
                curve_intensity = curve(intensity);
                phase_unwrap_no_curve = (phase_unwrap - curve_phase);
                intensity_no_curve = intensity - curve_intensity;
                
                % thickness calculation
                wavelength = app.WavelengthnmEditField.Value * 10^(-3);
                refractive_index_diff = app.RefracIndexDiffEditField.Value;
                factor = wavelength/(2*pi*refractive_index_diff);
                thickness = (factor * phase_unwrap_no_curve)';
                
                % caution!!! set negative thickness to 0
                if app.LowerLimitCheckBox.Value == 1
                    thickness(thickness < 0) = 0;
                end
                
                % apply median filter for 5 neighbouring pixels
                % thickness = medfilt2(thickness, [5, 5]);
                
                % apply weighted moving average filter for 5 neighbouring
                % pixels using conv2
                conv_mask = ones(5, 5) / 5^2;
                thickness = conv2(thickness, conv_mask, 'same');
                                
                % preset pixel size
                d_x = app.PixelSizemEditField.Value;
                d_y = app.PixelSizemEditField.Value;
                frame_peak_height = max(thickness, [], 'all');
                frame_volume = sum(thickness * d_x * d_y, 'all');
                
                % highlight spatial region
                % show_crop_region = logimgmaxmin_masked;
                
                dimmed_fftimg = logimgmaxmin;
                dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                    region_box(1) : (region_box(1) + region_box(3))) = ...
                    dimmed_fftimg(   region_box(2) : (region_box(2) + region_box(4)), ...
                    region_box(1) : (region_box(1) + region_box(3))) + 0.2;
                
                % Upper/lower colorbar limit
                if app.UpperLimitEditField.Value == 0
                    upper_limit = max(thickness, [], 'all');
                else
                    upper_limit = app.UpperLimitEditField.Value;
                end
                
                lower_limit = min(thickness, [], 'all');
                
                % save intensity and thickness images                
                f = figure('visible','off');
                imagesc(intensity_no_curve); title('Intensity'); colorbar;
                saveas(gca, [save_folder '\intensity\' 'intensity_' file]); caxis([-10, 10]);
                imagesc(thickness); title('Thickness'); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
                saveas(gca, [save_folder '\thickness\' 'thickness_' file]);
                imagesc(dimmed_fftimg); title('Spatial Frequency'); colorbar;
                saveas(gca, [save_folder '\fft\' 'fft_' file]);
                mesh(thickness); colorbar; zlim([lower_limit, upper_limit]); caxis([lower_limit, upper_limit]);
                text = ['Peak Height: ' num2str(frame_peak_height) newline 'Volume: ' num2str(frame_volume)];
                annotation('textbox', dim, 'String', text, 'FitBoxToText', 'on');
                saveas(gca, [save_folder '\mesh\' 'mesh_' file]);
                
                % save .mat for thickness
                if app.SaveHeightCheckBox.Value == 1
                    writematrix(thickness, [save_folder '\thickness_data\' erase(file, '.tif') '.csv']);
                    peak_height = [peak_height; [count frame_peak_height]];
                end
                
                if app.SaveVolumeCheckBox.Value == 1
                    volume = [volume; [count frame_volume]];
                end

                app.ImagePathTextArea_3.Value = ['Currently processing image: ' file newline num2str(length(up) - count) ' images to go...'];
                count = count + 1;
                
                % display intensity, phase image and spatial frequency, no unwrapping
                %     figure;
                %     subplot(3, 3, 1), imagesc(imgo); title('Hologram');
                %     subplot(3, 3, 2), imagesc(intensity); title('Intensity'); colorbar;
                %     subplot(3, 3, 3), imagesc(intensity_no_curve); title('Intensity Curve Removed'); colorbar;
                %     subplot(3, 3, 4), imagesc(phase); title('Phase'); colorbar;
                %     subplot(3, 3, 5), imagesc(phase_unwrap); title('Phase Unwrapped'); colorbar;
                %     subplot(3, 3, 6), imagesc(phase_unwrap_no_curve); title('Phase Unwrapped Curve Removed'); colorbar;
                %     subplot(3, 3, 7), imagesc(show_crop_region); title('Spatial Frequency Cropping'); colorbar;
                %     subplot(3, 3, 8), imagesc(thickness); title('Thickness'); colorbar;
                %     subplot(3, 3, 9), mesh(thickness); view(-100,50); title('Thickness'); colorbar;
            end
            
            if app.SaveHeightCheckBox.Value == 1
                writematrix(peak_height, [save_folder '\thickness_data\peak_height.csv']);
            end
            
            if app.SaveVolumeCheckBox.Value == 1
                writematrix(volume, [save_folder '\volume_data\volume.csv']);
            end
            
            t = toc;
            app.ImagePathTextArea_3.Value = ['Done! Total time: ' num2str(t) 's'];
        end

        % Button pushed function: RunCombineButton
        function RunCombineButtonPushed(app, event)
            tic;
            
            common_dir = app.common_directory;
            
            save_folder = strcat(app.desktop_path, app.ComninedSaveFolderEditField.Value);
            mkdir(save_folder);
            
            original_input = strcat(common_dir, '\original');
            fft_input = strcat(common_dir, '\fft');
            intensity_input = strcat(common_dir, '\intensity');
            thickness_input = strcat(common_dir, '\mesh');
            
            original_up = dir(strcat(original_input, '\*.tif'));
            fft_up = dir(strcat(fft_input, '\*.tif'));
            intensity_up = dir(strcat(intensity_input, '\*.tif'));
            thickness_up = dir(strcat(thickness_input, '\*.tif'));
            
            if length(original_up) == length(fft_up) && length(original_up) == length(intensity_up) && ...
                    length(original_up) == length(thickness_up) && length(fft_up) == length(intensity_up) && ...
                    length(fft_up) == length(thickness_up) && length(intensity_up) == length(thickness_up)
                up = length(original_up);
                
                count = 1;
                
                for index = 1 : up
                    close all;
                    original = original_up(index).name;
                    fft = fft_up(index).name;
                    intensity = intensity_up(index).name;
                    thickness = thickness_up(index).name;
                    
                    original_dir = strcat(original_input, '\', original);
                    fft_dir = strcat(fft_input, '\', fft);
                    intensity_dir = strcat(intensity_input, '\', intensity);
                    thickness_dir = strcat(thickness_input, '\', thickness);
                    
                    original_img = imread(original_dir);
                    fft_img = imread(fft_dir);
                    intensity_img = imread(intensity_dir);
                    thickness_img = imread(thickness_dir);
                    
                    combine = imtile({original_img, fft_img, intensity_img, thickness_img}, 'BackgroundColor', 'white', 'GridSize', [2 2]);
                    f = figure('visible','off');
                    original = erase(original, 'original_');
                    imshow(combine); title(['Frame ' erase(original, '.tif')])
                    saveas(gcf, [save_folder '\' 'combined_' original])

                    app.ImagePathTextArea_4.Value = ['Currently processing: ' erase(original, 'original_') newline num2str(up - count) ' images to go...'];
                    count = count + 1;
                end
                
                t = toc;
                app.ImagePathTextArea_4.Value = ['Done! Total time: ' num2str(t) 's'];
            else
                app.ImagePathTextArea_4.Value = ["Something is wrong. Did you copy and paste 'original' folder?" newline 'Or, check if numbers of figures across folders are equal.'];
            end
            

        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            imgo_preview = imread(app.image_path); % open hologram
            imshow(imgo_preview, 'parent', app.UIAxes);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [name, folder] = uigetfile('C:\*.tif');
            app.image_path = fullfile(folder, name);
            app.ImagePathTextArea.Value = app.image_path;
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
            [name, folder] = uigetfile('C:\*.avi');
            app.video_path = fullfile(folder, name);
            app.ImagePathTextArea_2.Value = app.video_path;
            
            video = VideoReader(app.video_path);
            app.FrameWidthEditField.Value = video.Width;
            app.FrameHeightEditField.Value = video.Height;
            app.SaveFolderEditField.Value = [name '_image_folder'];
        end

        % Button pushed function: ImageFolderButton
        function ImageFolderButtonPushed(app, event)
            app.image_folder_path = uigetdir;
            app.ImagePathTextArea_3.Value = app.image_folder_path;
        end

        % Button pushed function: CommonDirectoryButton
        function CommonDirectoryButtonPushed(app, event)
            app.common_directory = uigetdir;
            app.ImagePathTextArea_4.Value = app.common_directory;
        end

        % Button pushed function: PreviewCroppingButton
        function PreviewCroppingButtonPushed(app, event)
            video = VideoReader(app.video_path);
            
            x_start = app.UpperLeftXEditField.Value;
            y_start = app.UpperLeftYEditField.Value;
            width = app.FrameWidthEditField.Value;
            height = app.FrameHeightEditField.Value;
            
            line_width = 2;
            
            video.CurrentTime = app.StartTimesecEditField.Value;
            frame = readFrame(video);
            img_original = frame;
            img_original(y_start : y_start + height - 1, x_start : x_start + line_width) = 255;
            img_original(y_start : y_start + height - 1, x_start + width - 1 - line_width : x_start + width - 1) = 255;
            img_original(y_start : y_start + line_width, x_start : x_start + width - 1) = 255;
            img_original(y_start + height - 1 - line_width : y_start + height - 1, x_start : x_start + width - 1) = 255;
            figure('name', 'Cropping Preview');
            imshow(img_original);
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            if strcmp(app.mode, app.CameraModeDropDown.Value)
                time = datetime('now');
                app.diskLogger = VideoWriter(strcat(app.desktop_path, '_', string(time.Hour), '_', string(time.Minute), '_', app.FileSaveNameEditField.Value), 'Grayscale AVI');
                app.diskLogger.FrameRate = app.FramerateSaveEditField.Value;
                app.vid.DiskLogger = app.diskLogger;
                app.src.ExposureTime = app.ExposuresEditField.Value;
            else
                StartPreviewButtonPushed(app, event);
            end
        end

        % Button pushed function: ROISelectButton
        function ROISelectButtonPushed(app, event)
            frame = getsnapshot(app.vid);
            figure('Name', 'ROI Select')
            imshow(frame, [min(frame(:)) max(frame(:))]);
            roi = drawrectangle;
            app.vid.ROIPosition = floor(roi.Position);
            close 'ROI Select';
        end

        % Button pushed function: ROIResetButton
        function ROIResetButtonPushed(app, event)
            app.vid.ROIPosition = [0 0 2048 1536];
        end

        % Button pushed function: ShowFFTButton
        function ShowFFTButtonPushed(app, event)
            app.fft_check_control = 1;
            figure('Name', 'FFT Check');
            while app.fft_check_control == 1
                img = getsnapshot(app.vid);
                logimg = log(abs(fftshift(fft2(double(img)))));
                logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
                imshow(logimgmaxmin);
            end
        end

        % Button pushed function: CloseFFTButton
        function CloseFFTButtonPushed(app, event)
            app.fft_check_control = 0;
            close 'FFT Check';
        end

        % Button pushed function: LiveReconButton
        function LiveReconButtonPushed(app, event)
            figure('Name', 'FFT Cropping for Live Reconstruction');
            img = getsnapshot(app.vid);
            app.live_recon_control = 1;
            logimg = log(abs(fftshift(fft2(double(img)))));
            logimgmaxmin = (logimg - min(min(logimg)))/(max(max(logimg))-min(min(logimg))); % normalise
            imshow(logimgmaxmin);
            roi_live = drawrectangle;
            first_order = floor(roi_live.Position);
            close 'FFT Cropping for Live Reconstruction';
            
            app.live_recon_control = 1;
            figure('Name', 'Live Reconstruction');
            while app.live_recon_control == 1
                img = getsnapshot(app.vid);
                fftimg = fftshift(fft2(img)); % 2d fourier transform and translate to centre
                imgsize = size(fftimg); % determine image size
                
                fftimgcrop = fftimg(first_order(2):first_order(2) + first_order(4), first_order(1):first_order(1) + first_order(3)); % locate first order
                centreimg = zeros(imgsize(1), imgsize(2)); % create a black image for placing first order
                centreimg(floor(imgsize(1)/2):floor(imgsize(1)/2)+first_order(4), floor(imgsize(2)/2):floor(imgsize(2)/2)+first_order(3)) = fftimgcrop; % place first order at centre
                phase = angle(ifft2(ifftshift(centreimg))); % phase
                phase_unwrap = double(Miguel_2D_unwrapper(single(phase)));
                
%                 wavelength = app.WavelengthnmEditField.Value * 10^(-3);
%                 refractive_index_diff = app.RefracIndexDiffEditField.Value;
%                 factor = wavelength/(2*pi*refractive_index_diff);
%                 thickness = (factor * phase_unwrap);
%                 thickness(thickness < 0) = 0;
%                 conv_mask = ones(5, 5) / 5^2;
%                 thickness = conv2(thickness, conv_mask, 'same');
%                 thickness = imresize(thickness, app.PixelSizemEditField.Value);
                mesh(phase_unwrap); zlim([0 Inf]); colorbar;
            end
        end

        % Button pushed function: CloseReconButton
        function CloseReconButtonPushed(app, event)
            app.live_recon_control = 0;
            close 'Live Reconstruction';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DHMGUIUIFigure = uifigure('Visible', 'off');
            app.DHMGUIUIFigure.Name = 'DHM GUI';
            app.NoiseLevelEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.NoiseLevelEditFieldLabel.Text = 'Noise Level';
            app.NoiseLevelEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.NoiseLevelEditField.Limits = [0 100];
            app.NoiseLevelEditField.Value = 30;
            app.RunAutoButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.RunAutoButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutoButtonPushed, true);
            app.RunAutoButton.Text = 'Run Auto';
            app.RunManualButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.RunManualButton.ButtonPushedFcn = createCallbackFcn(app, @RunManualButtonPushed, true);
            app.RunManualButton.Text = 'Run Manual';
            app.ManualFrequencyCroppingLabel = uilabel(app.DHMGUIUIFigure);
            app.ManualFrequencyCroppingLabel.Text = 'Manual Frequency Cropping';
            app.AutoFrequencyCroppingLabel = uilabel(app.DHMGUIUIFigure);
            app.AutoFrequencyCroppingLabel.Text = 'Auto Frequency Cropping';
            app.FileSaveNameEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.FileSaveNameEditFieldLabel.Text = 'File Save Name';
            app.FileSaveNameEditField = uieditfield(app.DHMGUIUIFigure, 'text');
            app.FileSaveNameEditField.Value = 'video.avi';
            app.ImagingLabel = uilabel(app.DHMGUIUIFigure);
            app.ImagingLabel.Text = 'Imaging';
            app.SingleFrameReconstructionLabel = uilabel(app.DHMGUIUIFigure);
            app.SingleFrameReconstructionLabel.Text = 'Single Frame Reconstruction';
            app.ROISelectionLabel = uilabel(app.DHMGUIUIFigure);
            app.ROISelectionLabel.Text = 'ROI Selection';
            app.ConvertButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.UpperLeftXEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.UpperLeftXEditFieldLabel.Text = 'Upper Left X';
            app.UpperLeftXEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.UpperLeftXEditField.Limits = [1 Inf];
            app.UpperLeftXEditField.Value = 1;
            app.UpperLeftYEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.UpperLeftYEditFieldLabel.Text = 'Upper Left Y';
            app.UpperLeftYEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.UpperLeftYEditField.Limits = [1 Inf];
            app.UpperLeftYEditField.Value = 1;
            app.FrameWidthEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.FrameWidthEditFieldLabel.Text = 'Frame Width';
            app.FrameWidthEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.FrameWidthEditField.Limits = [1 Inf];
            app.FrameWidthEditField.Value = 2048;
            app.FrameHeightEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.FrameHeightEditFieldLabel.Text = 'Frame Height';
            app.FrameHeightEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.FrameHeightEditField.Limits = [1 Inf];
            app.FrameHeightEditField.Value = 1536;
            app.FrameIntevalEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.FrameIntevalEditFieldLabel.Text = 'Frame Inteval';
            app.FrameIntevalEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.FrameIntevalEditField.Limits = [1 Inf];
            app.FrameIntevalEditField.Value = 1;
            app.StartTimesecEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.StartTimesecEditField.Limits = [0 Inf];
            app.EndTimesecEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.EndTimesecEditFieldLabel.Text = 'End Time (sec)';
            app.EndTimesecEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.EndTimesecEditField.Limits = [0 Inf];
            app.RunBulkButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.RunBulkButton.ButtonPushedFcn = createCallbackFcn(app, @RunBulkButtonPushed, true);
            app.RunBulkButton.Text = 'Run Bulk';
            app.BatchFrameReconstructionLabel = uilabel(app.DHMGUIUIFigure);
            app.BatchFrameReconstructionLabel.Text = 'Batch Frame Reconstruction';
            app.StartPreviewButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Text = 'Start Preview';
            app.StartRecordButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = 'Start Record';
            app.StopRecordButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.StopRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButtonPushed, true);
            app.StopRecordButton.Text = 'Stop Record';
            app.StopPreviewButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.StopPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StopPreviewButtonPushed, true);
            app.StopPreviewButton.Text = 'Stop Preview';
            app.NoiseLevelEditField_2Label = uilabel(app.DHMGUIUIFigure);
            app.NoiseLevelEditField_2Label.Text = 'Noise Level';
            app.NoiseLevelEditField_2 = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.NoiseLevelEditField_2.Limits = [0 100];
            app.NoiseLevelEditField_2.Value = 30;
            app.RunCombineButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.RunCombineButton.ButtonPushedFcn = createCallbackFcn(app, @RunCombineButtonPushed, true);
            app.RunCombineButton.Text = 'Run Combine';
            app.SaveFolderEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.SaveFolderEditFieldLabel.Text = 'Save Folder';
            app.SaveFolderEditField = uieditfield(app.DHMGUIUIFigure, 'text');
            app.SaveFolderEditField.Value = 'image_folder';
            app.SaveFolderEditField_2Label = uilabel(app.DHMGUIUIFigure);
            app.SaveFolderEditField_2Label.Text = 'Save Folder';
            app.SaveFolderEditField_2 = uieditfield(app.DHMGUIUIFigure, 'text');
            app.SaveFolderEditField_2.Value = 'figure_folder';
            app.ComninedSaveFolderEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.ComninedSaveFolderEditFieldLabel.Text = 'Comnined Save Folder';
            app.ComninedSaveFolderEditField = uieditfield(app.DHMGUIUIFigure, 'text');
            app.ComninedSaveFolderEditField.Value = 'combined_folder';
            app.UIAxes = uiaxes(app.DHMGUIUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.PreviewButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.SelectImageButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImagePathTextArea = uitextarea(app.DHMGUIUIFigure);
            app.ImagePathTextArea.Editable = 'off';
            app.SelectVideoButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.ImagePathTextArea_2 = uitextarea(app.DHMGUIUIFigure);
            app.ImagePathTextArea_2.Editable = 'off';
            app.ImageFolderButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.ImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFolderButtonPushed, true);
            app.ImageFolderButton.Text = 'Image Folder';
            app.ImagePathTextArea_3 = uitextarea(app.DHMGUIUIFigure);
            app.ImagePathTextArea_3.Editable = 'off';
            app.CommonDirectoryButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.CommonDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CommonDirectoryButtonPushed, true);
            app.CommonDirectoryButton.Text = {'Common'; 'Directory'};
            app.ImagePathTextArea_4 = uitextarea(app.DHMGUIUIFigure);
            app.ImagePathTextArea_4.Editable = 'off';
            app.PreviewCroppingButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.PreviewCroppingButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewCroppingButtonPushed, true);
            app.PreviewCroppingButton.Text = 'Preview Cropping';
            app.CalculationParametersLabel = uilabel(app.DHMGUIUIFigure);
            app.CalculationParametersLabel.Text = 'Calculation Parameters';
            app.WavelengthnmEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.WavelengthnmEditField.Limits = [1 Inf];
            app.WavelengthnmEditField.Value = 514;
            app.RefracIndexDiffEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.RefracIndexDiffEditFieldLabel.Text = 'Refrac. Index Diff.';
            app.RefracIndexDiffEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.RefracIndexDiffEditField.Limits = [0 1];
            app.RefracIndexDiffEditField.Value = 0.06;
            app.UpperLimitEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.UpperLimitEditField.Limits = [0 Inf];
            app.UpperLimitEditField.Value = 20;
            app.InvertZaxisCheckBox = uicheckbox(app.DHMGUIUIFigure);
            app.InvertZaxisCheckBox.Text = 'Invert Z-axis';
            app.SaveHeightCheckBox = uicheckbox(app.DHMGUIUIFigure);
            app.SaveHeightCheckBox.Text = 'Save Height';
            app.SaveHeightCheckBox.Value = true;
            app.SaveVolumeCheckBox = uicheckbox(app.DHMGUIUIFigure);
            app.SaveVolumeCheckBox.Text = 'Save Volume';
            app.SaveVolumeCheckBox.Value = true;
            app.LowerLimitCheckBox = uicheckbox(app.DHMGUIUIFigure);
            app.LowerLimitCheckBox.Text = 'Lower Limit';
            app.LowerLimitCheckBox.Value = true;
            app.InvertZaxisCheckBox_2 = uicheckbox(app.DHMGUIUIFigure);
            app.InvertZaxisCheckBox_2.Text = 'Invert Z-axis';
            app.LowerLimitCheckBox_2 = uicheckbox(app.DHMGUIUIFigure);
            app.LowerLimitCheckBox_2.Text = 'Lower Limit';
            app.LowerLimitCheckBox_2.Value = true;
            app.UpperLimitEditField_2Label = uilabel(app.DHMGUIUIFigure);
            app.UpperLimitEditField_2Label.Text = 'Upper Limit';
            app.UpperLimitEditField_2 = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.UpperLimitEditField_2.Limits = [0 Inf];
            app.UpperLimitEditField_2.Value = 20;
            app.PixelSizemEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.PixelSizemEditFieldLabel.Text = 'Pixel Size (μm)';
            app.PixelSizemEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.PixelSizemEditField.Limits = [0 Inf];
            app.PixelSizemEditField.Value = 0.5;
            app.ApplyChangesButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ExposuresEditField_2Label = uilabel(app.DHMGUIUIFigure);
            app.ExposuresEditField_2Label.Text = 'Exposure (μs)';
            app.ExposuresEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.ExposuresEditField.Limits = [12 15003];
            app.ExposuresEditField.ValueDisplayFormat = '%.0f';
            app.ExposuresEditField.Value = 500;
            app.CameraModeDropDownLabel = uilabel(app.DHMGUIUIFigure);
            app.CameraModeDropDownLabel.Text = 'Camera Mode';
            app.CameraModeDropDown = uidropdown(app.DHMGUIUIFigure);
            app.CameraModeDropDown.Items = {'Mono16', 'Mono8', 'Mono10Packed', 'Mono12Packed'};
            app.CameraModeDropDown.Value = 'Mono16';
            app.FramerateLiveEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.FramerateLiveEditFieldLabel.Text = 'Framerate (Live)';
            app.FramerateLiveEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.FramerateLiveEditField.ValueDisplayFormat = '%.4f';
            app.FramerateLiveEditField.Editable = 'off';
            app.FramerateSaveEditFieldLabel = uilabel(app.DHMGUIUIFigure);
            app.FramerateSaveEditFieldLabel.Text = 'Framerate (Save)';
            app.FramerateSaveEditField = uieditfield(app.DHMGUIUIFigure, 'numeric');
            app.FramerateSaveEditField.ValueDisplayFormat = '%.4f';
            app.FramerateSaveEditField.Editable = 'off';
            app.ROISelectButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.ROISelectButton.ButtonPushedFcn = createCallbackFcn(app, @ROISelectButtonPushed, true);
            app.ROISelectButton.Text = 'ROI Select';
            app.ROIResetButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.ROIResetButton.ButtonPushedFcn = createCallbackFcn(app, @ROIResetButtonPushed, true);
            app.ROIResetButton.Text = 'ROI Reset';
            app.ShowFFTButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.ShowFFTButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFFTButtonPushed, true);
            app.ShowFFTButton.Text = 'Show FFT';
            app.CloseFFTButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.CloseFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFFTButtonPushed, true);
            app.CloseFFTButton.Text = 'Close FFT';
            app.LiveReconButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.LiveReconButton.ButtonPushedFcn = createCallbackFcn(app, @LiveReconButtonPushed, true);
            app.LiveReconButton.Text = 'Live Recon';
            app.CloseReconButton = uibutton(app.DHMGUIUIFigure, 'push');
            app.CloseReconButton.ButtonPushedFcn = createCallbackFcn(app, @CloseReconButtonPushed, true);
            app.CloseReconButton.Text = 'Close Recon';
            % Show the figure after all components are created
            app.DHMGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_ui_new_naming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DHMGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DHMGUIUIFigure)
        end
    end
end